classdef winServerStatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        EditionModeGrid        matlab.ui.container.GridLayout
        EditionModeLabel       matlab.ui.control.Label
        ButtonGroupPanel       matlab.ui.container.ButtonGroup
        ButtonGroup_Edit       matlab.ui.control.RadioButton
        ButtonGroup_View       matlab.ui.control.RadioButton
        toolGrid               matlab.ui.container.GridLayout
        DesconectadoLampLabel  matlab.ui.control.Label
        DesconectadoLamp       matlab.ui.control.Lamp
        toolButton_edit        matlab.ui.control.Button
        MainPanel              matlab.ui.container.Panel
        MainPanelGrid          matlab.ui.container.GridLayout
        Table                  ccTools.Table
        general_versionPanel   matlab.ui.container.Panel
        general_versionGrid    matlab.ui.container.GridLayout
        general_version        matlab.ui.control.HTML
        Tab2_GridTitle         matlab.ui.container.GridLayout
        plot_refresh           matlab.ui.control.Image
        Tab2_Image             matlab.ui.control.Image
        Tab2_Title             matlab.ui.control.Label
        Tab1_GridTitle         matlab.ui.container.GridLayout
        Tab1_Image             matlab.ui.control.Image
        Tab1_Title             matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        tcpServer
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function Layout(app)
            if isempty(app.tcpServer)
                app.general_version.HTMLSource = ' ';

                app.DesconectadoLamp.Color = [.64 .08 .18];
                app.DesconectadoLampLabel.Text = 'Servidor não está em execução.';

            else
                verMetaData(1).group = 'CARACTERÍSTICAS';
                verMetaData(1).value = struct('ServerAddress',     app.tcpServer.Server.ServerAddress,     ...
                                              'ServerPort',        app.tcpServer.Server.ServerPort,        ...
                                              'Connected',         app.tcpServer.Server.Connected,         ...
                                              'ClientAddress',     app.tcpServer.Server.ClientAddress,     ...
                                              'ClientPort',        app.tcpServer.Server.ClientPort,        ...
                                              'NumBytesAvailable', app.tcpServer.Server.NumBytesAvailable, ...
                                              'Timeout',           app.tcpServer.Server.Timeout,           ...
                                              'ByteOrder',         app.tcpServer.Server.ByteOrder,         ...
                                              'Terminator',        app.tcpServer.Server.Terminator,        ...
                                              'NumBytesWritten',   app.tcpServer.Server.NumBytesWritten);

                app.general_version.HTMLSource = fcn.metadataInfo(verMetaData);

                app.DesconectadoLamp.Color = [.47 .67 .19];
                app.DesconectadoLampLabel.Text = sprintf('Servidor em execução desde %s.', char(app.tcpServer.Time));

                app.Table.Data = app.tcpServer.Server.LOG;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            fcn.winPosition(app)

            app.tcpServer  = app.CallingApp.tcpServer;

            % Ajustes iniciais no layout do app, tornando invisíveis itens 
            % que aparecerão apenas no "MODO DE EDIÇÃO". E, por fim,
            % organização da informação nos componentes.

            app.EditionModeGrid.Visible = 0;
            app.toolButton_edit.Visible = 0;

            Layout(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            app.CallingApp.auxiliarWin5 = [];
            delete(app)
            
        end

        % Selection changed function: ButtonGroupPanel
        function ValueChanged_OperationMode(app, event)
            
            %-------------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %-------------------------------------------------------------%
            if app.ButtonGroup_View.Value
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de visualização:

                app.EditionModeGrid.Visible = 0;
                app.toolButton_edit.Visible = 0;

            %-------------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %-------------------------------------------------------------%
            else
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de edição:

                app.EditionModeGrid.Visible = 1;
                app.toolButton_edit.Visible = 1;

                if isempty(app.tcpServer)
                    set(app.toolButton_edit, Text='Iniciar servidor', Icon='LT_play.png')
                else
                    set(app.toolButton_edit, Text='Parar servidor', Icon='LT_stop.png')
                end
            end

        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            d = layoutFcn.modalWindow(app.UIFigure, 'ccTools.ProgressDialog');

            if isempty(app.tcpServer)
                app.CallingApp.tcpServer = class.tcpServerLib(app.CallingApp);
            else
                delete(app.CallingApp.tcpServer.Server)
                app.CallingApp.tcpServer = [];
            end

            pause(1)
            Layout(app)

            delete(d)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanelGrid = uigridlayout(app.MainPanel);
            app.Tab1_GridTitle = uigridlayout(app.MainPanelGrid);
            app.Tab1_GridTitle.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_GridTitle);
            app.Tab1_Title.Text = 'SERVIDOR';
            app.Tab1_Image = uiimage(app.Tab1_GridTitle);
            app.Tab1_Image.ImageSource = 'LT_Cloud.png';
            app.Tab2_GridTitle = uigridlayout(app.MainPanelGrid);
            app.Tab2_GridTitle.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_GridTitle);
            app.Tab2_Title.Text = 'COMUNICAÇÃO';
            app.Tab2_Image = uiimage(app.Tab2_GridTitle);
            app.Tab2_Image.ImageSource = 'LT_connect.png';
            app.plot_refresh = uiimage(app.Tab2_GridTitle);
            app.plot_refresh.ImageSource = 'LT_refresh.png';
            app.general_versionPanel = uipanel(app.MainPanelGrid);
            app.general_versionGrid = uigridlayout(app.general_versionPanel);
            app.general_version = uihtml(app.general_versionGrid);
            app.Table = ccTools.Table(app.MainPanelGrid);
            app.toolGrid = uigridlayout(app.GridLayout);
            app.toolButton_edit = uibutton(app.toolGrid, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'LT_play.png';
            app.toolButton_edit.Text = 'Iniciar servidor';
            app.DesconectadoLamp = uilamp(app.toolGrid);
            app.DesconectadoLampLabel = uilabel(app.toolGrid);
            app.DesconectadoLampLabel.Text = 'Desconectado';
            app.ButtonGroupPanel = uibuttongroup(app.GridLayout);
            app.ButtonGroupPanel.SelectionChangedFcn = createCallbackFcn(app, @ValueChanged_OperationMode, true);
            app.ButtonGroup_View = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_View.Text = 'Visualizar parâmetros';
            app.ButtonGroup_View.Value = true;
            app.ButtonGroup_Edit = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_Edit.Text = 'Editar parâmetros';
            app.EditionModeGrid = uigridlayout(app.GridLayout);
            app.EditionModeLabel = uilabel(app.EditionModeGrid);
            app.EditionModeLabel.Text = 'MODO DE EDIÇÃO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winServerStatus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end