classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        toolGrid                      matlab.ui.container.GridLayout
        toolButton_edit               matlab.ui.control.Button
        MainPanel                     matlab.ui.container.Panel
        MainPanelGrid                 matlab.ui.container.GridLayout
        Tab1_Panel                    matlab.ui.container.Panel
        general_Grid                  matlab.ui.container.GridLayout
        Tab2_Panel                    matlab.ui.container.Panel
        server_Grid                   matlab.ui.container.GridLayout
        server_Port                   matlab.ui.control.NumericEditField
        server_PortLabel              matlab.ui.control.Label
        server_IP                     matlab.ui.control.EditField
        server_IPLabel                matlab.ui.control.Label
        server_ClientList             matlab.ui.control.EditField
        server_ClientListLabel        matlab.ui.control.Label
        server_Key                    matlab.ui.control.EditField
        server_KeyLabel               matlab.ui.control.Label
        server_Status                 matlab.ui.control.DropDown
        server_StatusLabel            matlab.ui.control.Label
        general_gpu                   matlab.ui.control.DropDown
        general_gpuLabel              matlab.ui.control.Label
        general_versionLabel          matlab.ui.control.Label
        general_stationPanel          matlab.ui.container.Panel
        general_stationGrid           matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        general_stationStartupInfo    matlab.ui.control.CheckBox
        LeituradedadosarmazenadosnaltimasessoLabel  matlab.ui.control.Label
        general_stationComputer       matlab.ui.control.EditField
        general_stationComputerLabel  matlab.ui.control.Label
        general_stationName           matlab.ui.control.EditField
        general_stationNameLabel      matlab.ui.control.Label
        general_stationLabel          matlab.ui.control.Label
        general_versionPanel          matlab.ui.container.Panel
        general_versionGrid           matlab.ui.container.GridLayout
        general_version               matlab.ui.control.HTML
        Tab3_Panel                    matlab.ui.container.Panel
        plot_Grid                     matlab.ui.container.GridLayout
        plot_IntegrationPanel         matlab.ui.container.Panel
        plot_IntegrationGrid          matlab.ui.container.GridLayout
        plot_IntegrationTime          matlab.ui.control.NumericEditField
        plot_IntegrationTimeLabel     matlab.ui.control.Label
        plot_IntegrationTrace         matlab.ui.control.NumericEditField
        plot_IntegrationTraceLabel    matlab.ui.control.Label
        plot_IntegrationLabel         matlab.ui.control.Label
        plot_WaterfallPanel           matlab.ui.container.Panel
        plot_WaterfallGrid            matlab.ui.container.GridLayout
        plot_WaterfallDepth           matlab.ui.control.DropDown
        plot_WaterfallDepthLabel      matlab.ui.control.Label
        plot_WaterfallColormap        matlab.ui.control.DropDown
        plot_WaterfallColormapLabel   matlab.ui.control.Label
        plot_WaterfallLabel           matlab.ui.control.Label
        plot_colorsGrid               matlab.ui.container.GridLayout
        plot_colorsClearWrite         matlab.ui.control.Button
        plot_colorsMaxHold            matlab.ui.control.Button
        plot_colorsAverage            matlab.ui.control.Button
        plot_colorsMinHold            matlab.ui.control.Button
        plot_colorsLabel              matlab.ui.control.Label
        Tab3_Grid                     matlab.ui.container.GridLayout
        plot_refresh                  matlab.ui.control.Image
        Tab3_Image                    matlab.ui.control.Image
        Tab3_Title                    matlab.ui.control.Label
        Tab2_GridTitle                matlab.ui.container.GridLayout
        Tab2_Image                    matlab.ui.control.Image
        Tab2_Title                    matlab.ui.control.Label
        Tab1_GridTitle                matlab.ui.container.GridLayout
        Tab1_Image                    matlab.ui.control.Image
        Tab1_Title                    matlab.ui.control.Label
        EditionModeGrid               matlab.ui.container.GridLayout
        EditionModeLabel              matlab.ui.control.Label
        ButtonGroupPanel              matlab.ui.container.ButtonGroup
        ButtonGroup_Edit              matlab.ui.control.RadioButton
        ButtonGroup_View              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        General

        editedGeneral
        editedFlag = false
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function Layout(app)
            % PAINEL "VERSÃO"                
            verMetaData(1).group = 'SISTEMA OPERACIONAL';
            verMetaData(1).value = struct('Version', app.editedGeneral.version.OS);
            
            verMetaData(2).group = 'MATLAB';
            verMetaData(2).value = app.editedGeneral.version.Matlab;
            
            verMetaData(3).group = 'APPCOLETA';
            verMetaData(3).value = app.editedGeneral.version.appColeta;
            
            verMetaData(4).group = 'UNIDADE GRÁFICA';
            verMetaData(4).value = app.editedGeneral.version.OpenGL;
            
            verMetaData(5).group = 'PATH';
            verMetaData(5).value = struct('RootFolder', app.RootFolder, ...
                                          'userPath',   app.editedGeneral.userPath);

            app.general_version.HTMLSource = fcn.metadataInfo(verMetaData);


            % PAINEL "CONFIGURAÇÕES GERAIS"
            app.general_stationName.Value        = app.editedGeneral.stationInfo.Name;
            app.general_stationComputer.Value    = app.editedGeneral.stationInfo.Computer;
            app.general_stationStartupInfo.Value = app.editedGeneral.startupInfo;

            switch app.editedGeneral.tcpServer.Status
                case 0; app.server_Status.Items  = {'OFF'};
                case 1; app.server_Status.Items  = {'ON'};
            end

            app.server_Key.Value        = app.editedGeneral.tcpServer.Key;
            app.server_ClientList.Value = strjoin(app.editedGeneral.tcpServer.ClientList, ', ');
            app.server_IP.Value         = app.editedGeneral.tcpServer.IP;
            app.server_Port.Value       = app.editedGeneral.tcpServer.Port;
            
            graphRender = opengl('data');
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.general_gpu.Items = {'hardwarebasic'};
                case 'full';  app.general_gpu.Items = {'hardware'};
                case 'none';  app.general_gpu.Items = {'software'};
            end


            % PAINEL "PLOT"
            app.plot_colorsMinHold.BackgroundColor    = app.editedGeneral.Colors(1,:);
            app.plot_colorsAverage.BackgroundColor    = app.editedGeneral.Colors(2,:);
            app.plot_colorsMaxHold.BackgroundColor    = app.editedGeneral.Colors(3,:);
            app.plot_colorsClearWrite.BackgroundColor = app.editedGeneral.Colors(4,:);
            
            app.plot_WaterfallColormap.Items          = {app.editedGeneral.Waterfall.Colormap};
            app.plot_WaterfallDepth.Items             = {num2str(app.editedGeneral.Waterfall.Depth)};

            app.plot_IntegrationTrace.Value           = app.editedGeneral.Integration.Trace;
            app.plot_IntegrationTime.Value            = app.editedGeneral.Integration.SampleTime;
        end


        %-----------------------------------------------------------------%
        function status = IPv4Validation(app, ipAddress)

            status   = true;
            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');
            
            if isempty(ipString)
                status = false;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    status = false;
                end                    
            end
        end


        %-----------------------------------------------------------------%
        function saveNewFile(app)

            appGeneral = rmfield(app.General, 'version');
            appGeneral.stationInfo.Computer = '';

            try
                fileID = fopen(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json'), 'wt');
                fwrite(fileID, jsonencode(appGeneral, 'PrettyPrint', true));
                fclose(fileID);

                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'O arquivo <b>GeneralSettings.json</b> foi salvo.');
            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % do arquivo "GeneralSettings.json".
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            fcn.winPosition(app)

            app.General    = app.CallingApp.General;
            app.editedGeneral = app.General;
            
            % Ajustes iniciais no layout do app, tornando invisíveis itens 
            % que aparecerão apenas no "MODO DE EDIÇÃO". E, por fim,
            % organização da informação nos componentes.

            app.EditionModeGrid.Visible = 0;
            app.toolButton_edit.Visible = 0;
            app.plot_refresh.Visible    = 0;

            Layout(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.editedFlag
                saveNewFile(app)
                
                app.CallingApp.General = app.General;
                eval(sprintf('opengl %s', app.General.openGL))
            end

            app.CallingApp.auxiliarWin1 = [];
            delete(app)
            
        end

        % Selection changed function: ButtonGroupPanel
        function ValueChanged_OperationMode(app, event)
            
            %-------------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %-------------------------------------------------------------%
            if app.ButtonGroup_View.Value
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de visualização:

                app.EditionModeGrid.Visible = 0;
                app.toolButton_edit.Visible = 0;
                app.plot_refresh.Visible    = 0;

                % Desabilita edição do conteúdo dos campos...

                set(findobj(app.general_stationGrid,  'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.server_Grid,          'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.plot_WaterfallGrid,   'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.plot_IntegrationGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                
                app.general_stationStartupInfo.Enable = 0;

                set(app.server_Status,          'Items', {app.server_Status.Value})
                set(app.general_gpu,            'Items', {app.general_gpu.Value})
                set(app.plot_WaterfallColormap, 'Items', {app.plot_WaterfallColormap.Value})
                set(app.plot_WaterfallDepth,    'Items', {app.plot_WaterfallDepth.Value})

                % Essa última validação é essencial para desfazer alterações 
                % que não foram salvas. Ou seja, o usuário fez alterações
                % em app.General (que estavam armazenadas na sua cópia -
                % app.editedGeneral) e não clicou no botão "Confirma edição".

                if ~isequal(app.General, app.editedGeneral)
                    app.editedGeneral = app.General;
                    Layout(app)
                end

            %-------------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %-------------------------------------------------------------%
            else
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de edição:

                app.EditionModeGrid.Visible = 1;
                app.toolButton_edit.Visible = 1;
                app.plot_refresh.Visible    = 1;

                % Habilita edição do conteúdo dos campos...

                set(findobj(app.general_stationGrid,  'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.server_Grid,          'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.plot_WaterfallGrid,   'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.plot_IntegrationGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                
                app.general_stationComputer.Editable  = 'off';
                app.general_stationStartupInfo.Enable = 1;

                set(app.server_Status,          'Items', {'ON', 'OFF'})
                set(app.general_gpu,            'Items', {'hardwarebasic', 'hardware', 'software'})
                set(app.plot_WaterfallColormap, 'Items', {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'})
                set(app.plot_WaterfallDepth,    'Items', {'64', '128', '256', '512'})
            end

        end

        % Value changed function: general_gpu, general_stationName, 
        % ...and 10 other components
        function ValueChanged_Parameter(app, event)
            
            switch event.Source
                %---------------------------------------------------------%
                case app.general_stationName
                    if ~isempty(regexp(app.general_stationName.Value, '^EMSat$|^ERMx-[A-Z]{2}-[0-9][1-9]$', 'once'))
                        app.editedGeneral.stationInfo.Name = app.general_stationName.Value;
                    else
                        app.general_stationName.Value = event.PreviousValue;
                        layoutFcn.modalWindow(app.UIFigure, "ccTools.MessageBox", 'Formato esperado: "EMSat" ou "ERMx-UF-XX", sendo "UF" a sigla da unidade da federação e XX dois dígitos numéricos (01 a 99). Por exemplo: "ERMx-BA-01".');
                    end


                %---------------------------------------------------------%
                case app.general_stationStartupInfo
                    app.editedGeneral.startupInfo = app.general_stationStartupInfo.Value;
                    

                %---------------------------------------------------------%
                case app.server_Status
                    switch app.server_Status.Value
                        case 'ON';  app.editedGeneral.tcpServer.Status = 1;
                        case 'OFF'; app.editedGeneral.tcpServer.Status = 0;
                    end

                case app.server_Key
                    app.server_Key.Value = replace(app.server_Key.Value, ' ', '');
                    app.editedGeneral.tcpServer.Key = app.server_Key.Value;

                case app.server_ClientList
                    app.server_ClientList.Value = replace(app.server_ClientList.Value, ' ', '');
                    
                    if isempty(app.server_ClientList.Value)
                        app.editedGeneral.tcpServer.ClientList = {};
                    else
                        app.editedGeneral.tcpServer.ClientList = strsplit(app.server_ClientList.Value, ',');
                    end

                    app.server_ClientList.Value = strjoin(app.editedGeneral.tcpServer.ClientList, ', ');

                case app.server_IP
                    app.server_IP.Value = strtrim(app.server_IP.Value);

                    if IPv4Validation(app, app.server_IP.Value)
                        app.editedGeneral.tcpServer.IP = app.server_IP.Value;
                    else
                        app.server_IP.Value = event.PreviousValue;
                        layoutFcn.modalWindow(app.UIFigure, "ccTools.MessageBox", 'Endereço inválido (IPv4).');
                    end

                case app.server_Port
                    app.editedGeneral.tcpServer.Port = app.server_Port.Value;


                %---------------------------------------------------------%
                case app.general_gpu
                    app.editedGeneral.openGL = app.general_gpu.Value;


                %---------------------------------------------------------%
                case {app.plot_colorsMinHold, app.plot_colorsAverage, app.plot_colorsMaxHold, app.plot_colorsClearWrite}
                    switch event.Source
                        case app.plot_colorsMinHold;    idx = 1;
                        case app.plot_colorsAverage;    idx = 2;
                        case app.plot_colorsMaxHold;    idx = 3;
                        case app.plot_colorsClearWrite; idx = 4;
                    end
                    app.editedGeneral.Colors(idx,:) = event.Source.BackgroundColor';

                %---------------------------------------------------------%
                case app.plot_WaterfallColormap
                    app.editedGeneral.Waterfall.Colormap = app.plot_WaterfallColormap.Value;

                case app.plot_WaterfallDepth
                    app.editedGeneral.Waterfall.Depth = str2double(app.plot_WaterfallDepth.Value);

                
                %---------------------------------------------------------%
                case app.plot_IntegrationTrace
                    app.editedGeneral.Integration.Trace = app.plot_IntegrationTrace.Value;

                case app.plot_IntegrationTime
                    app.editedGeneral.Integration.SampleTime = app.plot_IntegrationTime.Value;
            end
            
        end

        % Image clicked function: plot_refresh
        function plotImageClicked_refresh(app, event)
            
            defaultGeneral = struct('Colors',     [0.38,0.60,0.73;  ...
                                                   0.39,0.83,0.07;  ...
                                                   1.00,0.07,0.65;  ...
                                                   0.93,0.69,0.13], ...
                                    'Waterfall',   struct('Colormap', 'winter', 'Depth', 512), ...
                                    'Integration', struct('Trace', 10, 'SampleTime', 10));

            if ~isequal(app.editedGeneral.Colors,      defaultGeneral.Colors)    || ...
               ~isequal(app.editedGeneral.Waterfall,   defaultGeneral.Waterfall) || ...
               ~isequal(app.editedGeneral.Integration, defaultGeneral.Integration)

                app.editedGeneral.Colors      = defaultGeneral.Colors;
                app.editedGeneral.Waterfall   = defaultGeneral.Waterfall;
                app.editedGeneral.Integration = defaultGeneral.Integration;

                Layout(app)
                ValueChanged_OperationMode(app)
            end

        end

        % Button pushed function: plot_colorsAverage, 
        % ...and 3 other components
        function plotButtonPushed_colors(app, event)
            
            if app.ButtonGroup_Edit.Value
                initialColor  = event.Source.BackgroundColor;
                selectedColor = uisetcolor(initialColor);
                figure(app.UIFigure)
    
                if ~isequal(initialColor, selectedColor)
                    switch event.Source
                        case app.plot_colorsMinHold;    app.plot_colorsMinHold.BackgroundColor    = selectedColor;
                        case app.plot_colorsAverage;    app.plot_colorsAverage.BackgroundColor    = selectedColor;
                        case app.plot_colorsMaxHold;    app.plot_colorsMaxHold.BackgroundColor    = selectedColor;
                        case app.plot_colorsClearWrite; app.plot_colorsClearWrite.BackgroundColor = selectedColor;
                    end
    
                    ValueChanged_Parameter(app, event)
                end
            end

        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            % Finalizada a edição, avalia-se se algum parâmetro foi, de fato, 
            % alterado, colocando essa informação na variável app.editedFlag.
            % Por fim, volta-se ao "MODO DE VISUALIZAÇÃO", clicando-se
            % programaticamente no botão de controle e, posteriormente,
            % acionando o seu callback.

            if ~isequal(app.General, app.editedGeneral)
                app.General    = app.editedGeneral;
                app.editedFlag = true;
            end
            
            app.ButtonGroup_View.Value = 1;
            ValueChanged_OperationMode(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ButtonGroupPanel = uibuttongroup(app.GridLayout);
            app.ButtonGroupPanel.SelectionChangedFcn = createCallbackFcn(app, @ValueChanged_OperationMode, true);
            app.ButtonGroup_View = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_View.Text = 'Visualizar parâmetros';
            app.ButtonGroup_View.Value = true;
            app.ButtonGroup_Edit = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_Edit.Text = 'Editar parâmetros';
            app.EditionModeGrid = uigridlayout(app.GridLayout);
            app.EditionModeLabel = uilabel(app.EditionModeGrid);
            app.EditionModeLabel.Text = 'MODO DE EDIÇÃO';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanelGrid = uigridlayout(app.MainPanel);
            app.Tab1_GridTitle = uigridlayout(app.MainPanelGrid);
            app.Tab1_GridTitle.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_GridTitle);
            app.Tab1_Title.Text = 'VERSÃO';
            app.Tab1_Image = uiimage(app.Tab1_GridTitle);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab2_GridTitle = uigridlayout(app.MainPanelGrid);
            app.Tab2_GridTitle.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_GridTitle);
            app.Tab2_Title.Text = 'CONFIGURAÇÕES GERAIS';
            app.Tab2_Image = uiimage(app.Tab2_GridTitle);
            app.Tab2_Image.ImageSource = 'LT_Dots2.png';
            app.Tab3_Grid = uigridlayout(app.MainPanelGrid);
            app.Tab3_Grid.Tag = 'COLORLOCKED';
            app.Tab3_Title = uilabel(app.Tab3_Grid);
            app.Tab3_Title.Text = 'PLOT';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageSource = 'LT_Detection.png';
            app.plot_refresh = uiimage(app.Tab3_Grid);
            app.plot_refresh.ImageClickedFcn = createCallbackFcn(app, @plotImageClicked_refresh, true);
            app.plot_refresh.ImageSource = 'LT_refresh.png';
            app.Tab3_Panel = uipanel(app.MainPanelGrid);
            app.plot_Grid = uigridlayout(app.Tab3_Panel);
            app.plot_colorsLabel = uilabel(app.plot_Grid);
            app.plot_colorsLabel.Text = 'Cores:';
            app.plot_colorsGrid = uigridlayout(app.plot_Grid);
            app.plot_colorsMinHold = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsMinHold.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsMinHold.Text = 'MinHold';
            app.plot_colorsAverage = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsAverage.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsAverage.Text = 'Tendência central';
            app.plot_colorsMaxHold = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsMaxHold.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsMaxHold.Text = 'MaxHold';
            app.plot_colorsClearWrite = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsClearWrite.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsClearWrite.Text = 'ClearWrite';
            app.plot_WaterfallLabel = uilabel(app.plot_Grid);
            app.plot_WaterfallLabel.Text = 'Waterfall:';
            app.plot_WaterfallPanel = uipanel(app.plot_Grid);
            app.plot_WaterfallGrid = uigridlayout(app.plot_WaterfallPanel);
            app.plot_WaterfallColormapLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallColormapLabel.Text = 'Mapa de cor:';
            app.plot_WaterfallColormap = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallColormap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.plot_WaterfallColormap.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_WaterfallColormap.Value = 'jet';
            app.plot_WaterfallDepthLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallDepthLabel.Text = 'Profundidade:';
            app.plot_WaterfallDepth = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallDepth.Items = {'64', '128', '256', '512'};
            app.plot_WaterfallDepth.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_WaterfallDepth.Value = '128';
            app.plot_IntegrationLabel = uilabel(app.plot_Grid);
            app.plot_IntegrationLabel.Text = 'Integração:';
            app.plot_IntegrationPanel = uipanel(app.plot_Grid);
            app.plot_IntegrationGrid = uigridlayout(app.plot_IntegrationPanel);
            app.plot_IntegrationTraceLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTraceLabel.Text = {'Traço médio:'; '(gráfico)'};
            app.plot_IntegrationTrace = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTrace.Limits = [3 100];
            app.plot_IntegrationTrace.RoundFractionalValues = 'on';
            app.plot_IntegrationTrace.ValueDisplayFormat = '%d';
            app.plot_IntegrationTrace.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_IntegrationTrace.Editable = 'off';
            app.plot_IntegrationTrace.Value = 10;
            app.plot_IntegrationTimeLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTimeLabel.Text = {'Tempo de escrita médio:'; '(barra de ferramentas)'};
            app.plot_IntegrationTime = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTime.Limits = [3 100];
            app.plot_IntegrationTime.RoundFractionalValues = 'on';
            app.plot_IntegrationTime.ValueDisplayFormat = '%d';
            app.plot_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_IntegrationTime.Editable = 'off';
            app.plot_IntegrationTime.Value = 10;
            app.general_versionPanel = uipanel(app.MainPanelGrid);
            app.general_versionGrid = uigridlayout(app.general_versionPanel);
            app.general_version = uihtml(app.general_versionGrid);
            app.Tab1_Panel = uipanel(app.MainPanelGrid);
            app.general_Grid = uigridlayout(app.Tab1_Panel);
            app.general_stationLabel = uilabel(app.general_Grid);
            app.general_stationLabel.Text = 'Estação:';
            app.general_stationPanel = uipanel(app.general_Grid);
            app.general_stationGrid = uigridlayout(app.general_stationPanel);
            app.general_stationNameLabel = uilabel(app.general_stationGrid);
            app.general_stationNameLabel.Text = 'Nome:';
            app.general_stationName = uieditfield(app.general_stationGrid, 'text');
            app.general_stationName.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_stationName.Editable = 'off';
            app.general_stationComputerLabel = uilabel(app.general_stationGrid);
            app.general_stationComputerLabel.Text = 'Computador:';
            app.general_stationComputer = uieditfield(app.general_stationGrid, 'text');
            app.general_stationComputer.Editable = 'off';
            app.GridLayout2 = uigridlayout(app.general_stationGrid);
            app.LeituradedadosarmazenadosnaltimasessoLabel = uilabel(app.GridLayout2);
            app.LeituradedadosarmazenadosnaltimasessoLabel.Text = 'Leitura de dados armazenados na última sessão.';
            app.general_stationStartupInfo = uicheckbox(app.GridLayout2);
            app.general_stationStartupInfo.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_stationStartupInfo.Enable = 'off';
            app.general_stationStartupInfo.Text = '';
            app.general_versionLabel = uilabel(app.general_Grid);
            app.general_versionLabel.Text = 'Servidor TCP:';
            app.general_gpuLabel = uilabel(app.general_Grid);
            app.general_gpuLabel.Text = 'Unidade gráfica:';
            app.general_gpu = uidropdown(app.general_Grid);
            app.general_gpu.Items = {};
            app.general_gpu.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_gpu.Value = {};
            app.Tab2_Panel = uipanel(app.general_Grid);
            app.server_Grid = uigridlayout(app.Tab2_Panel);
            app.server_StatusLabel = uilabel(app.server_Grid);
            app.server_StatusLabel.Text = 'Estado:';
            app.server_Status = uidropdown(app.server_Grid);
            app.server_Status.Items = {'ON', 'OFF'};
            app.server_Status.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_Status.Value = 'ON';
            app.server_KeyLabel = uilabel(app.server_Grid);
            app.server_KeyLabel.Text = 'Chave:';
            app.server_Key = uieditfield(app.server_Grid, 'text');
            app.server_Key.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_Key.Editable = 'off';
            app.server_ClientListLabel = uilabel(app.server_Grid);
            app.server_ClientListLabel.Text = {'Lista de clientes:'; '(valores separados por vírgula)'};
            app.server_ClientList = uieditfield(app.server_Grid, 'text');
            app.server_ClientList.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_ClientList.Editable = 'off';
            app.server_IPLabel = uilabel(app.server_Grid);
            app.server_IPLabel.Text = 'Endereço IP (OpenVPN):';
            app.server_IP = uieditfield(app.server_Grid, 'text');
            app.server_IP.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_IP.Editable = 'off';
            app.server_PortLabel = uilabel(app.server_Grid);
            app.server_PortLabel.Text = 'Porta:';
            app.server_Port = uieditfield(app.server_Grid, 'numeric');
            app.server_Port.Limits = [1 65535];
            app.server_Port.RoundFractionalValues = 'on';
            app.server_Port.ValueDisplayFormat = '%d';
            app.server_Port.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_Port.Editable = 'off';
            app.server_Port.Value = 1;
            app.toolGrid = uigridlayout(app.GridLayout);
            app.toolButton_edit = uibutton(app.toolGrid, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'LT_edit.png';
            app.toolButton_edit.Text = 'Confirma edição';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end