classdef winTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid               matlab.ui.container.GridLayout
        CancelButton       matlab.ui.control.Button
        ErrorLabel         matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        AzimuthError       matlab.ui.control.Label
        PolarizationError  matlab.ui.control.Label
        ElevationError     matlab.ui.control.Label
        PolarizationLabel  matlab.ui.control.Label
        ElevationLabel     matlab.ui.control.Label
        AzimuthLabel       matlab.ui.control.Label
        Polarization       matlab.ui.control.Gauge
        Elevation          matlab.ui.control.NinetyDegreeGauge
        Azimuth            matlab.ui.control.Gauge
        PositionGrid       matlab.ui.container.GridLayout
        actualPosValue     matlab.ui.control.Label
        actualPosLabel     matlab.ui.control.Label
        finalPosValue      matlab.ui.control.Label
        finalPosLabel      matlab.ui.control.Label
        initialPosValue    matlab.ui.control.Label
        initialPosLabel    matlab.ui.control.Label
        antennaNameLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder
        EMSatObj

        UpdateStatus = true
        antennaName
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function screenStartup(app, targetPos, antennaPos)

            app.initialPosValue.Text = sprintf('(%.3fº, %.3fº, %.3fº)', antennaPos.Azimuth, antennaPos.Elevation, antennaPos.Polarization);
            app.finalPosValue.Text   = sprintf('(%.3fº, %.3fº, %.3fº)', targetPos.Azimuth,  targetPos.Elevation,  targetPos.Polarization);
            screenUpdate(app, targetPos, antennaPos)

            app.Azimuth.Value = antennaPos.Azimuth;
            if targetPos.Azimuth ~= antennaPos.Azimuth
                set(app.Azimuth, 'ScaleColors', [1 1 0],      ...
                                 'ScaleColorLimits', sort([targetPos.Azimuth, antennaPos.Azimuth]))
            end

            app.Elevation.Value = antennaPos.Elevation;
            if targetPos.Elevation ~= antennaPos.Elevation
                set(app.Elevation, 'ScaleColors', [1 1 0],        ...
                                   'ScaleColorLimits', sort([targetPos.Elevation, antennaPos.Elevation]))
            end

            app.Polarization.Value = antennaPos.Polarization;
            if targetPos.Polarization ~= antennaPos.Polarization
                set(app.Polarization, 'ScaleColors', [1 1 0],           ...
                                      'ScaleColorLimits', sort([targetPos.Polarization, antennaPos.Polarization]))
            end

            drawnow
        end


        %-----------------------------------------------------------------%
        function screenUpdate(app, targetPos, antennaPos)

            app.actualPosValue.Text  = sprintf('(%.3fº, %.3fº, %.3fº)', antennaPos.Azimuth, antennaPos.Elevation, antennaPos.Polarization);

            app.AzimuthError.Text      = sprintf('Erro: %.3fº', abs(targetPos.Azimuth      - antennaPos.Azimuth));
            app.ElevationError.Text    = sprintf('Erro: %.3fº', abs(targetPos.Elevation    - antennaPos.Elevation));
            app.PolarizationError.Text = sprintf('Erro: %.3fº', abs(targetPos.Polarization - antennaPos.Polarization));


        end


        %-----------------------------------------------------------------%
        function setNewPosition(app, targetPos, antennaPos)

            if abs(targetPos.Azimuth      - antennaPos.Azimuth)      >= 0.2 && ...
               abs(targetPos.Elevation    - antennaPos.Elevation)    >= 0.2 && ...
               abs(targetPos.Polarization - antennaPos.Polarization) >= 0.2
                targetPos.Name         = antennaPos.Name;
                targetPos.TrackingMode = antennaPos.TrackingMode;
                if isfield(antennaPos, 'Target')
                    targetPos.Target   = antennaPos.Target;
                end
                
                while true
                    msgError = app.EMSatObj.AntennaPositionSET(targetPos);
    
                    if ~isempty(msgError)
                        msg = sprintf('<font style="font-size:11;">%s\n\nDeseja tentar mais uma vez?</font>', msgError);    
                        
                        selection = uiconfirm(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Options', {'Sim', 'Não'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question');                
                        if selection == "Sim"
                            continue
                        else
                            error(msgError)
                        end
                    end
    
                    break
                end
            end
        end


        %-----------------------------------------------------------------%
        function updatePosition(app, targetPos, antennaPos)

            initialPos = updateReference(app, antennaPos);

            trackTic = tic;
            while app.UpdateStatus
                if toc(trackTic) > 10
                    if isequal(initialPos, antennaPos)
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não alterado o apontamento da antena mesmo após decorridos 10 segundos...');
                    else
                        initialPos = updateReference(app, antennaPos);
                    end
                    trackTic = tic;
                end

                [newAntennaPos, errorMsg] = app.EMSatObj.AntennaPositionGET(app.antennaName);
                if ~isempty(errorMsg)
                    app.ErrorLabel.Text = errorMsg;
                    continue
                end
                antennaPos = newAntennaPos;

                app.Azimuth.Value      = antennaPos.Azimuth;
                app.Elevation.Value    = antennaPos.Elevation;
                app.Polarization.Value = antennaPos.Polarization;
                screenUpdate(app, targetPos, antennaPos)
                drawnow

                app.ErrorLabel.Text    = '';

                if abs(targetPos.Azimuth      - antennaPos.Azimuth)      < 0.2 && ...
                   abs(targetPos.Elevation    - antennaPos.Elevation)    < 0.2 && ...
                   abs(targetPos.Polarization - antennaPos.Polarization) < 0.2
                    break
                end
            end

            closeFcn(app)
        end


        %-----------------------------------------------------------------%
        function initialPos = updateReference(app, antennaPos)

            initialPos = struct('Azimuth',      antennaPos.Azimuth,   ...
                                'Elevation',    antennaPos.Elevation, ...
                                'Polarization', antennaPos.Polarization);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, targetPos, antennaPos)
            
            app.CallingApp  = mainapp;
            app.RootFolder  = app.CallingApp.RootFolder;
            fcn.winPosition(app)

            app.EMSatObj    = app.CallingApp.EMSatObj;
            app.antennaName = antennaPos.Name;

            screenStartup(app, targetPos, antennaPos)
            setNewPosition(app, targetPos, antennaPos)
            updatePosition(app, targetPos, antennaPos)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            app.UpdateStatus = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.antennaNameLabel = uilabel(app.Grid);
            app.antennaNameLabel.Text = 'MCL-1';
            app.PositionGrid = uigridlayout(app.Grid);
            app.initialPosLabel = uilabel(app.PositionGrid);
            app.initialPosLabel.Text = 'Posição inicial:';
            app.initialPosValue = uilabel(app.PositionGrid);
            app.initialPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.finalPosLabel = uilabel(app.PositionGrid);
            app.finalPosLabel.Text = 'Posição alvo:';
            app.finalPosValue = uilabel(app.PositionGrid);
            app.finalPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.actualPosLabel = uilabel(app.PositionGrid);
            app.actualPosLabel.Text = 'Posição atual:';
            app.actualPosValue = uilabel(app.PositionGrid);
            app.actualPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.Panel = uipanel(app.Grid);
            app.Azimuth = uigauge(app.Panel, 'circular');
            app.Azimuth.Limits = [0 360];
            app.Azimuth.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Azimuth.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.Elevation = uigauge(app.Panel, 'ninetydegree');
            app.Elevation.Limits = [0 90];
            app.Elevation.MajorTicks = [0 22.5 45 67.5 90];
            app.Elevation.MajorTickLabels = {'0', '22.5', '45', '67.5', '90'};
            app.Elevation.MinorTicks = [0 3 6 9 12 15 18 27 30 33 36 39 42 45 48 51 54 57 60 63 72 75 78 81 84 87 90];
            app.Polarization = uigauge(app.Panel, 'circular');
            app.Polarization.Limits = [0 360];
            app.Polarization.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Polarization.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.AzimuthLabel = uilabel(app.Panel);
            app.AzimuthLabel.Text = 'AZIMUTE';
            app.ElevationLabel = uilabel(app.Panel);
            app.ElevationLabel.Text = 'ELEVAÇÃO';
            app.PolarizationLabel = uilabel(app.Panel);
            app.PolarizationLabel.Text = 'POLARIZAÇÃO';
            app.ElevationError = uilabel(app.Panel);
            app.ElevationError.Text = 'Erro: 0.000º';
            app.PolarizationError = uilabel(app.Panel);
            app.PolarizationError.Text = 'Erro: 0.000º';
            app.AzimuthError = uilabel(app.Panel);
            app.AzimuthError.Text = 'Erro: 0.000º';
            app.ErrorLabel = uilabel(app.Grid);
            app.ErrorLabel.Text = '';
            app.CancelButton = uibutton(app.Grid, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'LT_redX.png';
            app.CancelButton.Tooltip = {''};
            app.CancelButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end