classdef winAppColetaV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        mainGrid             matlab.ui.container.GridLayout
        downPanel_Grid       matlab.ui.container.GridLayout
        Button_Play          matlab.ui.control.Button
        Button_Separator1_2  matlab.ui.control.Image
        taskRevisitTime      matlab.ui.control.Label
        Button_Settings      matlab.ui.control.Button
        Button_Separator2    matlab.ui.control.Image
        Button_LayoutRight   matlab.ui.control.Button
        Button_LayoutLeft    matlab.ui.control.Button
        Button_LayoutTop     matlab.ui.control.Button
        taskStatus           matlab.ui.control.Label
        Button_log           matlab.ui.control.Button
        Button_Separator1    matlab.ui.control.Image
        Button_Del           matlab.ui.control.Button
        Button_Edit          matlab.ui.control.Button
        Button_New           matlab.ui.control.Button
        upPanel_Grid         matlab.ui.container.GridLayout
        TaskInfo_Panel       matlab.ui.container.GridLayout
        lastGPS_Panel        matlab.ui.container.Panel
        lastGPS_Grid1        matlab.ui.container.GridLayout
        errorCount_img_2     matlab.ui.control.Image
        errorCount_txt_2     matlab.ui.control.Label
        lastGPS_Grid2        matlab.ui.container.GridLayout
        lastGPS_color        matlab.ui.control.Lamp
        lastGPS_text         matlab.ui.control.Label
        lastGPS_label        matlab.ui.control.Label
        lastMask_Panel       matlab.ui.container.Panel
        lastMask_Grid        matlab.ui.container.GridLayout
        lastMask_text        matlab.ui.control.Label
        lastMask_label       matlab.ui.control.Label
        Sweeps_Panel         matlab.ui.container.Panel
        Sweeps_Grid          matlab.ui.container.GridLayout
        errorCount_img       matlab.ui.control.Image
        errorCount_txt       matlab.ui.control.Label
        Sweeps               matlab.ui.control.Label
        Sweeps_Label         matlab.ui.control.Label
        Sweeps_REC           matlab.ui.control.Image
        PlotTool_Grid        matlab.ui.container.GridLayout
        Button_MaskPlot      matlab.ui.control.StateButton
        Button_Layout        matlab.ui.control.Button
        Button_MaxHold       matlab.ui.control.StateButton
        Button_Average       matlab.ui.control.StateButton
        Button_MinHold       matlab.ui.control.StateButton
        Plot_Panel           matlab.ui.container.Panel
        MetaData_Panel       matlab.ui.container.Panel
        MetaData_Grid        matlab.ui.container.GridLayout
        MetaData             matlab.ui.control.HTML
        MetaData_Label       matlab.ui.control.Label
        Tree                 matlab.ui.container.Tree
        Tree_Label           matlab.ui.control.Label
        Table                ccTools.Table
    end

    
    properties (Access = public)    
        RootFolder
        General

        specObj      = class.specClass.empty
        revisitObj
        timerObj
        taskList

        Flag_running = 0
        Flag_editing = 0
        plotLayout   = 1

        %-----------------------------------------------------------------%
        % PLOT
        %-----------------------------------------------------------------%
        axes1
        axes2
        
        line_ClrWrite
        line_MinHold
        line_Average
        line_MaxHold
        surface_WFall

        %-----------------------------------------------------------------%
        % COMMUNICATION
        %-----------------------------------------------------------------%
        tcpServer
        
        receiverObj
        gpsObj
        udpPortArray = {}

        EMSatObj
        EB500Obj

        %-----------------------------------------------------------------%
        % AUXILIAR APPS
        %-----------------------------------------------------------------%
        auxiliarWin1 = [] % auxApp.winSettings
        auxiliarWin2 = [] % auxApp.winAddTask
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % STARTUP
        %-----------------------------------------------------------------%
        function startup_WindowPosition(app)
            mainMonitor = get(0, 'MonitorPositions');
            [~, idx]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(idx,:);
            
            app.UIFigure.Position(3:4) = class.Constants.windowSize;
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            t = tiledlayout(app.Plot_Panel, 3, 1, "Padding", "tight", "TileSpacing", "none");

            % app.axes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            set(app.axes1, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], Interactions=[], XTickLabel={})
            ylabel(app.axes1, 'Nível (dB)')

            % app.axes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 2;
            app.axes2.Layout.TileSpan = [2,1];
            set(app.axes2, Color=[.98,.98,.98], FontName='Helvetica', FontSize=9, FontSmoothing='on', Interactions = [])
            xlabel(app.axes2, 'Frequência (MHz)')
            ylabel(app.axes2, 'Amostras')

            % Others aspects...
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')
            
            disableDefaultInteractivity(app.axes1)
            disableDefaultInteractivity(app.axes2)

            axtoolbar(app.axes1, {'datacursor', 'pan', 'zoomin', 'zoomout', 'restoreview'});
            axtoolbar(app.axes2, {'datacursor', 'pan', 'zoomin', 'zoomout', 'restoreview'});

            linkaxes([app.axes1, app.axes2], 'x')
            ButtonPushed_plotLayout(app)
        end

        %-----------------------------------------------------------------%
        function startup_ConfigFiles(app)
            % app.General
            global appGeneral
            tempObj = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            app.General = tempObj;

            userpaths = {fullfile(getenv('USERPROFILE'), 'Documents'); ...
                         fullfile(getenv('USERPROFILE'), 'Downloads')};

            if ~isempty(app.General.userPath)
                userpaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userpaths):-1:1
                if ~isfolder(userpaths{ii})
                    userpaths(ii) = [];
                end
            end
            app.General.userPath     = userpaths{end};
            app.General.RootFolder   = app.RootFolder;
            app.General.File.Version = class.Constants.fileVersion;

            appGeneral = app.General;

            % app.taskList
            [app.taskList, msgError] =  class.taskList.file2raw(app.RootFolder);
            if ~isempty(msgError)
                layoutFcn.modalWindow(app.UIFigure, "ccTools.MessageBox", msgError);
            end

            % Others...
            app.receiverObj = class.ReceiverLib(app.RootFolder);
            app.gpsObj      = class.GPSLib(app.RootFolder);
            app.EMSatObj    = class.EMSatLib(app.RootFolder);
            app.EB500Obj    = class.EB500Lib(app.RootFolder);
            app.tcpServer   = class.tcpServerLib(app); 
        end

        %-----------------------------------------------------------------%
        function startup_TimerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing",        ...
                                 "BusyMode",      "queue",               ...
                                 "StartDelay",    timer_StartDelay(app), ...
                                 "Period",        60,                    ...
                                 "TimerFcn",      @(~,~)app.timer_RegularTaskCheck);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        % TIMER 
        %-----------------------------------------------------------------%
        function StartDelay = timer_StartDelay(app)
            StartDelay = 60 - floor(second(datetime('now')));
        end

        %-----------------------------------------------------------------%
        function timer_RegularTaskCheck(app)
            if ~app.Flag_running
                Flag = false;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii, '')
                        Flag = true;
                        break
                    end
                end

                if Flag
                    RegularTask_MainLoop(app)
                end
            end

            if numel(app.specObj) ~= height(app.Table.Data)
                layoutFcn.tableBuilding(app, app.Table.Selection)
                TreeSelectionChanged(app)
            end
        end


        %-----------------------------------------------------------------%
        % REGULAR TASK
        %-----------------------------------------------------------------%
        function Flag = RegularTask_StatusTaskCheck(app, idx, evtName)
            % Função responsável por trocar o estado das tarefas, de "Na
            % fila" para "Em andamento", "Em andamento" para "Cancelada",
            % "Em andamento" para "Erro" e por aí vai...
            %
            % Lembrando que o estado de uma nova tarefa é "Na fila", exceto
            % quando ocorre algum erro no processo de criação (decorrente 
            % de uma configuração de um parâmetro não aceito pelo receptor,
            % por exemplo). Nesse caso, o estado será "Erro".
            %
            % Caso não exista alguma tarefa em execução, o app.Flag_running
            % será igual a 0, e o app.timerObj estará ativo, o que o fará
            % avaliar a cada minuto o estado de todas as tarefas, nesta 
            % função, de forma que:
            % (a) Seja iniciada uma tarefa no estado "Na fila";
            % (b) Seja realizada uma nova tentativa de iniciar uma tarefa 
            %     no estado "Erro" (o que ocorrerá a cada 15 minutos).
            
            Flag = false;
            Timestamp = datetime('now');
            
            switch app.specObj(idx).Status
                case 'Em andamento'
                    if app.specObj(idx).Observation.EndTime < Timestamp || ismember(evtName, {'DeleteButtonPushed', 'ErrorTrigger'})
                        Flag = true;

                        if app.specObj(idx).Observation.EndTime < Timestamp
                            app.specObj(idx).Status = 'Concluída';
                        else
                            switch evtName
                                case 'DeleteButtonPushed'
                                    app.specObj(idx).Status = 'Cancelada';
                                case 'ErrorTrigger'
                                    app.specObj(idx).Status = 'Erro';
                            end
                        end
                        
                        app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;
                        app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', sprintf('Alterado o estado da tarefa: Em andamento → %s.', app.specObj(idx).Status));

                        for ii = 1:numel(app.specObj(idx).Band)
                            app.specObj(idx) = class.RFlookBinLib.CloseFile(app.specObj(idx), ii);
                            app.specObj(idx).Band(ii).Status = false;
                        end

                    else
                        if strcmp(app.specObj(idx).Task.Script.Observation.Type, 'Samples')
                            tempFlag = [];                            
                            for ii = 1:numel(app.specObj(idx).Band)
                                if app.specObj(idx).Band(ii).Status
                                    if app.specObj(idx).Band(ii).File.WritedSamples == app.specObj(idx).Task.Script.Band(ii).instrObservationSamples
                                        app.specObj(idx) = class.RFlookBinLib.CloseFile(app.specObj(idx), ii);
                                        app.specObj(idx).Band(ii).Status = false;
                                        tempFlag(end+1) = true;

                                    else
                                        tempFlag(end+1) = false;
                                    end
                                end
                            end

                            if all(tempFlag)
                                Flag = true;

                                app.specObj(idx).Status = 'Concluída';
                                app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;
                                app.specObj(idx).Observation.EndTime = Timestamp;
                                app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', sprintf('Alterado o estado da tarefa: Em andamento → %s.', app.specObj(idx).Status));

                            elseif any(tempFlag)
                                Flag = true;
                            end
                        end
                    end

                case {'Na fila', 'Erro'}
                    if strcmp(app.specObj(idx).Status, 'Erro') 
                        if ~mod(minute(Timestamp), 1) % mudei de 15 pra 1
                            if isnat(app.specObj(idx).Observation.StartUp)
                                app.specObj(idx).Observation.StartUp = Timestamp;
                            end
    
                            StartUp = app.specObj(idx).Observation.StartUp;
                            if isequal([year(Timestamp), month(Timestamp), day(Timestamp), hour(Timestamp), minute(Timestamp)], ...
                                    [year(StartUp), month(StartUp), day(StartUp), hour(StartUp), minute(StartUp)])
                                return
                            end
                        else
                            return
                        end
                    end

                    if app.specObj(idx).Observation.BeginTime < Timestamp
                        switch app.specObj(idx).Task.Script.Observation.Type
                            case {'Duration', 'Time'}
                                if isnat(app.specObj(idx).Observation.EndTime) || (app.specObj(idx).Observation.EndTime > Timestamp)
                                    Flag = true;
                                end

                            case 'Samples'
                                Flag = true;
                        end
                    end

                    if Flag
                        try
                            if strcmp(app.timerObj.Running, 'on')
                                stop(app.timerObj)
                            end
                            RegularTask_StartUp(app, idx);

                            app.specObj(idx).Status = 'Em andamento';
                            app.specObj(idx).hReceiver.UserData.nTasks   = app.specObj(idx).hReceiver.UserData.nTasks+1;
                            app.specObj(idx).hReceiver.UserData.SyncMode = app.specObj(idx).Task.Receiver.Sync;
                            app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', 'Iniciada a execução da tarefa.');

                        catch ME
                            if strcmp(app.timerObj.Running, 'off') && ~app.Flag_running
                                start(app.timerObj)
                            end
                            app.specObj(idx).Status = 'Erro';
                            app.specObj(idx).LOG(end+1) = struct('type', 'error', 'time', char(Timestamp), 'msg', getReport(ME));
                        end
                    end
            end

            if Flag
                layoutFcn.tableBuilding(app, app.Table.Selection)
                TreeSelectionChanged(app)
            end
        end


        %-----------------------------------------------------------------%
        function RegularTask_StartUp(app, idx)
            Task     = app.specObj(idx).Task;
            baseName = sprintf('appColeta_%s', datestr(now, 'yymmdd_THHMMSS'));

            % HANDLES
            hReceiver  = app.specObj(idx).hReceiver;
            hStreaming = app.specObj(idx).hStreaming;
            hGPS       = app.specObj(idx).hGPS;

            % Ao que parece, essa nova interface TCPCLIENT possui alguns 
            % problemas, dentre eles:
            % (a) Não existe uma função que retorna os objetos TCPCLIENT 
            %     (como instrfind p/ os objetos TCPIP). 
            %
            % (b) Algumas chamadas a um objeto não mais válido (decorrente 
            %     de uma perda de conectividade, por exemplo) apresentam a 
            %     mensagem de erro na janela de comandos do Matlab, mesmo 
            %     "protegidos" num bloco try/catch. A execução não para, 
            %     mas imprimir a mensagem na janela de comandos é um 
            %     comportamento não esperado.
            %
            % (c) A informação que indica que o objeto não mais está conectado 
            %     ao instrumento somente é acessível usando struct.

            % RECEIVER TEST (DATAGRAMS ESTIMATION)
            if ~isempty(hStreaming)
                if contains(hReceiver.UserData.IDN, 'EB500') && isempty(app.specObj(idx).Band(1).Datagrams)
                    app.specObj(idx) = class.EB500Lib.DatagramRead_PreTask(app.EB500Obj, app.specObj(idx), hReceiver, hStreaming);
                end
            end

            % GPS TEST
            if ~isempty(hGPS)
                % TESTAR GPS EXTERNO
            end

            for ii = 1:numel(app.specObj(idx).Band)
                ThreadID = Task.Script.Band(ii).ID;

                % ANTENNA SWITCH & ACU TESTS
                if strcmp(Task.Antenna.Switch, 'EMSat')
%                     app.auxWin2 = winTracking(app, idx);
%                     drawnow


                % GUARDAR EM ALGUM LUGAR DO APP.SPECOBJ A INFORMAÇÃO DE
                % APONTAMENTO DA ANTENA, APÓS O "APONTAMENTO DE PRECISÃO".

                % Guardar em "rawMetaData", propriedade de "Band",
                % propriedade de app.specObj.

                % ROTINA:
                % (A) SETA MATRIZ PRA ANTENA OBJETO DO APONTAMENTO.
                % (B) COLOCA NAS COORDENADAS CADASTRADAS NA ACU.
                % (C) AJUSTA ANALISADOR - RBW, FREQ. DO BEACON ETC. CAPTURA
                % VALOR MÉDIO (AVERAGE, COM 10 TRAÇOS), DETECTOR RMS.
                % (D) VAI ANOTANDO VALORES E VARIANDO OS PARÂMETROS...
                % ... ALGUMA REGRA (DEEP LEARNING) PRA IDENTIFICAR A MELHOR
                % POSIÇÃO.
                end

                % MASK
                app.specObj(idx).Band(ii).Mask = [];
                if strcmp(Task.Type, 'Rompimento de Máscara Espectral') && Task.Script.Band(ii).Trigger
                    maskInfo  = class.maskLib.FileRead(Task.MaskFile);
                    maskArray = class.maskLib.ArrayConstructor(maskInfo, Task.Script.Band(ii));

                    app.specObj(idx).Band(ii).Mask = struct('Table', maskInfo.Table, 'Array', maskArray, 'Validations', 0,          ...
                                                            'BrokenArray', zeros(1, Task.Script.Band(ii).instrDataPoints), ...
                                                            'BrokenCount', 0, 'MainPeaks', '', 'TimeStamp', NaT);
                    app.specObj(idx).LOG(end+1)    = struct('type', 'mask', 'time', datestr(now), 'msg', sprintf('ID %.0f\n%s', ThreadID, jsonencode(maskInfo.Table)));
                end


                % FILE
                app.specObj(idx).Band(ii).File = struct('Fileversion', app.General.File.Version,              ...
                                                        'Basename', sprintf('%s_ID%.0f', baseName, ThreadID), ...
                                                        'Filecount', 0, 'WritedSamples', 0, 'CurrentFile', []);

                [app.specObj(idx).Band(ii).File.Filecount, ...
                    app.specObj(idx).Band(ii).File.CurrentFile] = class.RFlookBinLib.OpenFile(app.specObj(idx), ii);

                logMsg = sprintf(['ID: %.0f\n'             ...
                                  'scpiSet_Config: "%s"\n' ...
                                  'scpiSet_Att: "%s"\n'    ...
                                  'rawMetaData: "%s"\n'    ...
                                  'Filename (base): %s'], ThreadID,                                         ...
                                                          app.specObj(idx).Band(ii).SpecificSCPI.configSET, ...
                                                          app.specObj(idx).Band(ii).SpecificSCPI.attSET,    ...
                                                          app.specObj(idx).Band(ii).rawMetaData,            ...
                                                          app.specObj(idx).Band(ii).File.Basename);                
                app.specObj(idx).LOG(end+1) = struct('type', 'startup', 'time', datestr(now), 'msg', logMsg);


                % WATERFALL MATRIX
                DataPoints     = Task.Script.Band(ii).instrDataPoints;
                WaterfallDepth = app.General.Waterfall.Depth;   
                if strcmp(Task.Script.Observation.Type, 'Samples')
                    WaterfallDepth = min([WaterfallDepth, Task.Script.Band(ii).instrObservationSamples]);
                end                

                switch Task.Script.Band(ii).instrLevelUnit
                    case 'dBm';            refLevel = -120;
                    case {'dBµV', 'dBμV'}; refLevel = -13;
                end                

                app.specObj(idx).Band(ii).Waterfall = struct('idx', 0, 'Depth', WaterfallDepth, 'Matrix', -1000 .* ones(WaterfallDepth, DataPoints, 'single'));
            end
        end


        %-----------------------------------------------------------------%
        function RegularTask_MainLoop(app)
            app.Flag_running = 1;
            app.Flag_editing = 1;

            stop(app.timerObj)

            while app.Flag_running
                if app.Flag_editing
                    app.revisitObj = fcn.RevisitFactors(app.specObj);
                    if isempty(app.revisitObj.GlobalRevisitTime)
                        app.Flag_running = 0;
                        break
                    end
                    
                    nn = 0;
                    app.Flag_editing = 0;
                end

                sweepTic = tic;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii, '')
                        app.Flag_editing = 1;
                        break
                    end

                    if ~strcmp(app.specObj(ii).Status, 'Em andamento')
                        continue
                    end
                    
                    hReceiver  = app.specObj(ii).hReceiver;
                    hStreaming = app.specObj(ii).hStreaming;
                    hGPS       = app.specObj(ii).hGPS;

                    nBands = numel(app.specObj(ii).Band);    
                    for jj = 0:nBands
                        if mod(nn, app.revisitObj.Band(ii).RevisitFactors(jj+1)) || app.revisitObj.Band(ii).RevisitFactors(jj+1) == -1
                            continue
                        end
                        newTimeStamp = datetime('now');
    
                        if jj == 0
                            app.specObj(ii).lastGPS = RegularTask_gpsData(app, ii, hReceiver, hGPS, newTimeStamp);

                        else
                            app.specObj(ii) = class.RFlookBinLib.CheckFile(app.specObj(ii), jj);
                            
                            try
                                % ANTENNA SWITCH (IF APPLICABLE)
                                RegularTask_AntennaSwitch(app, ii, jj)

                                % RECEIVER RECONFIGURATION (IF APPLICABLE)
                                if (nBands > 1) || (hReceiver.UserData.nTasks > 1)
                                    writeline(hReceiver, app.specObj(ii).Band(jj).SpecificSCPI.configSET);
                                    pause(.001)
                                    
                                    if ~isempty(app.specObj(ii).Band(jj).SpecificSCPI.attSET)
                                        writeline(hReceiver, app.specObj(ii).Band(jj).SpecificSCPI.attSET);
                                    end
                                end

                                attFactor = -1;
                                if ~isempty(app.specObj(ii).GeneralSCPI.attGET)
                                    attFactor = str2double(writeread(hReceiver, app.specObj(ii).GeneralSCPI.attGET));
                                end

                                fileTrigger  = [];
                                maskTrigger  = [];

                                if isempty(app.specObj(ii).Band(jj).Mask)
                                    % SINGLE TRACE
                                    newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                    app.specObj(ii).Band(jj).nSweeps = app.specObj(ii).Band(jj).nSweeps+1;
                                
                                else
                                    % BURST OF TRACES
                                    nSweeps  = app.General.FindPeaks.nSweeps;
                                    newArray = zeros(nSweeps, app.specObj(ii).Band(jj).DataPoints, 'single');                                    
                                    for kk = 1:nSweeps
                                        newArray(kk,:) = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                        app.specObj(ii).Band(jj).nSweeps = app.specObj(ii).Band(jj).nSweeps+1;
                                    end
                                    smoothedArray = mean(newArray);

                                    % METADATA UPDATE
                                    app.specObj(ii).Band(jj).Mask.Validations = app.specObj(ii).Band(jj).Mask.Validations + 1;
                                    
                                    maskTrigger = false;
                                    switch app.specObj(ii).Task.Script.Band(jj).Trigger
                                        case 2;    fileTrigger = true;
                                        otherwise; fileTrigger = false;
                                    end

                                    % MASK BROKEN ANALISYS                                    
                                    validationArray = (smoothedArray - app.specObj(ii).Band(jj).Mask.Array) > 0;
                                    if any(validationArray)
                                        app.specObj(ii).Band(jj).Mask.BrokenArray = app.specObj(ii).Band(jj).Mask.BrokenArray + validationArray;

                                        peaksTable = fcn.FindPeaks(app.specObj(ii), jj, smoothedArray, validationArray, app.General.FindPeaks);
                                        if ~isempty(peaksTable)
                                            app.specObj(ii).Band(jj).Mask.BrokenCount = app.specObj(ii).Band(jj).Mask.BrokenCount + 1;
                                            app.specObj(ii).Band(jj).Mask.MainPeaks   = peaksTable;
                                            app.specObj(ii).Band(jj).Mask.TimeStamp   = newTimeStamp;
                                            
                                            maskTrigger = true;
                                        end
                                    end

                                    newArray = newArray(end,:);
                                end
                                
                                app.specObj(ii).Error(1,2:4) = {NaT, NaT, 0};

                                % WATERFALL MATRIX
                                idx = app.specObj(ii).Band(jj).Waterfall.idx + 1;
                                if idx > app.specObj(ii).Band(jj).Waterfall.Depth; idx = 1;
                                end

                                app.specObj(ii).Band(jj).Waterfall.idx = idx;
                                app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:) = newArray;

                                % ESTIMATED REVISIT TIME
                                if isempty(app.specObj(ii).Band(jj).LastTimeStamp)
                                    app.specObj(ii).Band(jj).RevisitTime = app.revisitObj.GlobalRevisitTime * app.revisitObj.Band(ii).RevisitFactors(jj+1);
                                else
                                    app.specObj(ii).Band(jj).RevisitTime = ((app.General.Integration.SampleTime-1)*app.specObj(ii).Band(jj).RevisitTime + seconds(newTimeStamp-app.specObj(ii).Band(jj).LastTimeStamp))/app.General.Integration.SampleTime;
                                end
                                app.specObj(ii).Band(jj).LastTimeStamp = newTimeStamp;

                                % PLOT, WRITEDSAMPLES & MASKINFO (IF APPLICABLE)
                                if app.Table.Selection == ii
                                    layoutFcn.errorCount(app, ii)
                                    if app.Tree.SelectedNodes.NodeData == jj
                                        plotFcn.Draw(app, ii, jj)
                                        if ~isempty(maskTrigger)
                                            layoutFcn.lastMaskValidation(app, maskTrigger, ii, jj)
                                        end
                                        app.taskRevisitTime.Text = sprintf('%d varreduras\n%.3f seg', app.specObj(ii).Band(jj).nSweeps, app.specObj(ii).Band(jj).RevisitTime);
                                    end
                                    drawnow
                                end

                                % FILE
                                if fileTrigger & ~maskTrigger
                                    continue
                                else
                                    class.RFlookBinLib.EditFile(app.specObj(ii), jj, newArray, attFactor, newTimeStamp)
                                    app.specObj(ii).Band(jj).File.WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples + 1;

                                    if (app.Table.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                        app.Sweeps.Text = string(app.specObj(ii).Band(jj).File.WritedSamples);
                                    end
                                    drawnow
                                end
    
                            catch ME
                                % O controle de erro do GPS se dá na função "RegularTask_gpsData".
                                % 
                                % O controle de erro do RECEPTOR se dá aqui, neste trecho da função 
                                % "RegularTask_MainLoop".
                                %
                                % O app tentará reativar a conexão toda vez que o contador de
                                % erro atingir um múltiplo de "class.Constants.errorCountTrigger".
                                % E, além disso, caso ultrapassado o tempo (em segundos) definido 
                                % em "class.Constants.errorTimeTrigger", o app trocará o estado da 
                                % tarefa de "Em andamento" → "Erro".

                                app.specObj(ii).LOG(end+1) = struct('type', 'error (RECEIVER)', 'time', char(newTimeStamp), 'msg', ME.message);
                                RegularTask_errorHandle(app, 'Receiver', ii, newTimeStamp)

                                if app.Table.Selection == ii
                                    layoutFcn.errorCount(app, ii)
                                end

                                if seconds(app.specObj(ii).Error.LastTime(1) - app.specObj(ii).Error.CreatedTime(1)) >= class.Constants.errorTimeTrigger
                                    RegularTask_StatusTaskCheck(app, ii, 'ErrorTrigger');

                                elseif ~mod(app.specObj(ii).Error.Count(1), class.Constants.errorCountTrigger)
                                    app.receiverObj = ReconnectAttempt(app.receiverObj, hReceiver.UserData.instrSelected, nBands, app.specObj(ii).Band(jj).SpecificSCPI);
                                end
                            end
                        end
                    end
                end

                nn = nn+1;
                pause(max(app.revisitObj.GlobalRevisitTime-toc(sweepTic), .001))
            end

            app.timerObj.StartDelay = timer_StartDelay(app);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function gpsData = RegularTask_gpsData(app, ii, hReceiver, hGPS, newTimeStamp)
            % O controle de erro do RECEPTOR se dá na função "RegularTask_MainLoop".
            %
            % O controle de erro do GPS, por outro lado, se dá diretamente aqui, 
            % nesta função, e é restrito ao caso em que o receptor é "External",
            % ou seja, não se trata de GPS embarcado no RECEPTOR (GPS conectado
            % à porta USB do computador que executa o app, por exemplo).
            %
            % Caso a tarefa seja do tipo "Drive-test", toda vez que for manifestada 
            % uma desconexão, o app tentará reativar a conexao. Ou, em sendo uma tarefa 
            % de outro tipo, o app tentará reativar a conexão toda vez que o contador de
            % erro atingir um múltiplo de "class.Constants.errorGPSCountTrigger".
             
            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'TimeStamp', '');

            try
                switch app.specObj(ii).Task.Script.GPS.Type
                    case 'Built-in'
                        gpsData = fcn.gpsBuiltInReader(hReceiver);
                    case 'External'
                        gpsData = fcn.gpsExternalReader(hGPS, 1);
                        app.specObj(ii).Error(2,2:4) = {NaT, NaT, 0};
                end

            catch ME
                app.specObj(ii).LOG(end+1) = struct('type', 'error (GPS)', 'time', char(newTimeStamp), 'msg', ME.message);

                if strcmp(app.specObj(ii).Task.Script.GPS.Type, 'External')
                    RegularTask_errorHandle(app, 'GPS', ii, newTimeStamp)

                    if strcmp(app.specObj(ii).Task.Type, 'Drive-test') || ~mod(app.specObj(ii).Error.Count(2), class.Constants.errorGPSCountTrigger)
                        app.gpsObj = ReconnectAttempt(app.gpsObj, hGPS.UserData.instrSelected);
                    end
                end
            end

            if isempty(gpsData.TimeStamp)
                gpsData.TimeStamp = char(newTimeStamp);
            end

            if (app.Table.Selection == ii)
                layoutFcn.lastGPS(app, gpsData)
            end
        end
        
        
        %-----------------------------------------------------------------%
        function RegularTask_AntennaSwitch(app, ii, jj)
            if ~isempty(app.specObj(ii).Task.Antenna.Switch)                
                msgError = app.EMSatObj.AntennaSwitch(jsondecode(app.specObj(ii).Band(jj).Antenna).Name);

                if isempty(msgError)
                    error(msgError)
                end
            end
        end


        %-----------------------------------------------------------------%
        function newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming)
            Timeout = class.Constants.Timeout;
            Flag_success = false;

            switch app.specObj(ii).Task.Receiver.Config.connectFlag
                case 1                                                      % Spectrum analyzers (R&S, KeySight, Tektronix, Anritsu)
                    recTic = tic;
                    t1 = toc(recTic);
                    while t1 < Timeout
                        try
                            writeline(hReceiver, app.specObj(ii).GeneralSCPI.dataGET);
                            newArray = readbinblock(hReceiver, 'single');
                                                        
                            if numel(newArray) == app.specObj(ii).Band(jj).DataPoints
                                if strcmp(app.specObj(ii).Task.Receiver.Sync, 'Continuous Sweep')
                                    SyncModeRef = sum(newArray);

                                    if SyncModeRef ~= app.specObj(ii).Band(jj).SyncModeRef
                                        app.specObj(ii).Band(jj).SyncModeRef = SyncModeRef;
                                    else
                                        continue
                                    end                                    
                                end

                                Flag_success = true;
                                break
                            end
    
                        catch
                        end
                        t1 = toc(recTic);
                    end
                    
                case 2                                                      % R&S EB500                    
                    taskInfo = struct('FreqStart',  app.specObj(ii).Task.Script.Band(jj).FreqStart, ...
                                      'FreqStop',   app.specObj(ii).Task.Script.Band(jj).FreqStop,  ...
                                      'DataPoints', app.specObj(ii).Band(jj).DataPoints,                     ...
                                      'nDatagrams', app.specObj(ii).Band(jj).Datagrams,                      ...
                                      'udpPort',    app.EB500Obj.udpPort);
                    [newArray, Flag_success] = class.EB500Lib.DatagramRead_Task(taskInfo, hReceiver, hStreaming);
            end
            flush(hReceiver)
            
            if Flag_success
                if app.specObj(ii).Band(jj).FlipArray
                    newArray = flip(newArray);
                end
            else
                error('Não foi lido corretamente o vetor de nível do receptor dentro do tempo limite (%.0f segundos).', Timeout)
            end            
        end


        %-----------------------------------------------------------------%
        function RegularTask_errorHandle(app, errorType, ii, newTimeStamp)
            switch errorType
                case 'Receiver'; idx = 1;
                case 'GPS';      idx = 2;
            end
                                
            if isnat(app.specObj(ii).Error.CreatedTime(idx))
                app.specObj(ii).Error.CreatedTime(idx) = newTimeStamp;
            end
            app.specObj(ii).Error.LastTime(idx) = newTimeStamp;
            app.specObj(ii).Error.Count(idx) = app.specObj(ii).Error.Count(idx) + 1;
        end


        %-----------------------------------------------------------------%
        function logMsg = Misc_logMsg(app, idx)
            logMsg = '';
            if ~isempty(app.specObj(idx).LOG)
                logTable = struct2table(app.specObj(idx).LOG);
                logMsg   = strjoin("<b>" + logTable.time + " - " + upper(logTable.type) + "</b>" + newline + logTable.msg, '\n\n');
            end
        end

    end

    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function publicFcn_winAddTask(app, infoEdition, newTask)
            % Closing the auxiliar app...
            delete(app.auxiliarWin2)
            app.auxiliarWin2 = [];
            
            % Passing data to the object
            [app.specObj, errorMsg] = app.specObj.AddOrEditTask(infoEdition, newTask, app.EMSatObj);
            
            if isempty(errorMsg)
                timer_RegularTaskCheck(app)                                 % Startup of every task
            else
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', errorMsg);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % PATH SEARCH
                if isdeployed
                    [~, result]    = system('path');
                    app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                    if ~isfile(fullfile(app.RootFolder, 'appColeta.exe'))
                        error('Não identificado o local de instalação do <i>app</i>.')
                    end                
                else
                    app.RootFolder = fileparts(mfilename('fullpath'));
                end

                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')

                % LOG (DIARY)
                diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))                
                diary on
                fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now)))

                % STARTUP
                startup_WindowPosition(app)
                startup_AxesCreation(app)
                startup_ConfigFiles(app)
                startup_TimerCreation(app)

            catch ME
                fprintf('%s\n', jsonencode(ME))
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            drawnow
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            % RUNNING TASK CHECK
            if app.Flag_running
                layoutFcn.modalWindow(app.UIFigure, "ccTools.MessageBox", "Existe uma tarefa em execução...");
                return
            end
            
            % LOG
            fprintf(sprintf('<EndTime>%s</EndTime>\n</LogEntry>\n', datestr(now)))
            diary off
            
            log = fileread(fullfile(app.RootFolder, 'Debug', '_log.txt'));
            if contains(log, 'error', 'IgnoreCase', true)
                writematrix(log, fullfile(app.RootFolder, 'Debug', '_error.txt'), "QuoteStrings", false, "WriteMode", "append")
                writematrix("",  fullfile(app.RootFolder, 'Debug', '_log.txt'),   "QuoteStrings", false, "WriteMode", "overwrite")
            end

            % DELETE TIMER
            h = timerfindall;
            stop(h); delete(h); clear h

            % DELETE APPS
            delete(app.auxiliarWin1)
            delete(app.auxiliarWin2)
            delete(app)
            
        end

        % Callback function: Table
        function TableSelectionChanged(app, event)

            layoutFcn.treeBuilding(app, 1)
            TreeSelectionChanged(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            try
                ii = app.Table.Selection;
                jj = app.Tree.SelectedNodes.NodeData;
    
                plotFcn.StartUp(app)
                if ~isempty(app.specObj(ii).Band(jj).Waterfall)
                    idx = app.specObj(ii).Band(jj).Waterfall.idx;
    
                    if idx
                        plotFcn.Draw(app, ii, jj)
                    end
                end
    
                % TASK INFO THAT ARE UPDATED IN REAL TIME
                % (LEFT PANEL)
                Task = app.specObj(ii).Task.Script;
    
                sRevisitTime = sprintf('%.3f seg', Task.Band(jj).RevisitTime);
                try
                    if app.revisitObj.Band(ii).RevisitFactors(jj+1) ~= -1
                        sRevisitTime = sprintf('%.3f → %.3f seg (norm)', Task.Band(jj).RevisitTime, ...
                                                                         app.revisitObj.GlobalRevisitTime * app.revisitObj.Band(ii).RevisitFactors(jj+1));
                    end
                catch
                end

                if isnumeric(Task.Band(jj).instrStepWidth)
                    StepWidth = sprintf('%.3f kHz', Task.Band(jj).instrStepWidth/1e+3);
                else
                    StepWidth = Task.Band(jj).instrStepWidth;
                end
    
                app.MetaData.HTMLSource = sprintf(['<font style="font-family: Helvetica; font-size: 10px;">'  ...
                                                   '<b style="color: #808080;">DESCRIÇÃO: </b>%s<br><br>',    ...
                                                   '<b style="color: #808080;">PASSO: </b>%s<br>',            ...
                                                   '<b style="color: #808080;">PONTOS: </b>%.0f<br>',         ...
                                                   '<b style="color: #808080;">RESOLUÇÃO: </b>%s<br>',        ...
                                                   '<b style="color: #808080;">DETECTOR: </b>%s<br><br>',     ...
                                                   '<b style="color: #808080;">TRAÇO: </b>%s<br>',            ...
                                                   '<b style="color: #808080;">INTEGRAÇÃO: </b>%.0f<br><br>', ...
                                                   '<b style="color: #808080;">REVISITA: </b>%s</font>'],     ...
                                                   Task.Band(jj).Description,           ...
                                                   StepWidth,                           ...
                                                   Task.Band(jj).instrDataPoints,       ...
                                                   Task.Band(jj).instrResolution,       ...
                                                   Task.Band(jj).instrDetector,         ...
                                                   Task.Band(jj).TraceMode,             ...
                                                   Task.Band(jj).IntegrationFactor,     ...                                               
                                                   sRevisitTime);
    
                % (RIGHT PANEL)
                if ~isempty(app.specObj(ii).Band(jj).File); WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples;
                else;                                       WritedSamples = -1; 
                end
                app.Sweeps.Text = string(WritedSamples);
    
                if strcmp(app.specObj(ii).Status, 'Em andamento') && app.specObj(ii).Band(jj).Status
                    app.Sweeps_REC.Visible = 1;
                else
                    app.Sweeps_REC.Visible = 0;
                end
    
                if ~isempty(app.specObj(ii).Band(jj).Mask)
                    set(app.Button_MaskPlot, 'Enable', 1)
                    app.lastMask_text.Enable = 1;
                    layoutFcn.lastMaskValidation(app, true, ii, jj)
                else
                    set(app.Button_MaskPlot, 'Enable', 0, 'Value', 0)
                    app.lastMask_text.Enable = 0;
                    app.lastMask_text.Text   = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; ''; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.000 kHz </font>'; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
                end
                layoutFcn.lastGPS(app, app.specObj(ii).lastGPS)
    
                % (DOWN STATUS PANEL)
                app.taskStatus.Text = sprintf('%s\n%s', app.Table.Data.Receiver(ii), app.Tree.SelectedNodes.Text);
                if ~isempty(app.taskRevisitTime.Text); app.taskRevisitTime.Text = sprintf('%d varreduras\n%.3f seg', app.specObj(ii).Band(jj).nSweeps, app.specObj(ii).Band(jj).RevisitTime);
                else;                                  app.taskRevisitTime.Text = '';
                end

            catch
                % Return to initial layout aspect...
                plotFcn.StartUp(app)
                app.MetaData.HTMLSource  = ' ';
                app.Sweeps.Text          = '-1';
                app.Sweeps_REC.Visible   = 0;
                layoutFcn.errorCount(app, 0)
                set(app.Button_MaskPlot, 'Enable', 0, 'Value', 0)
                app.lastMask_text.Enable = 0;
                app.lastMask_text.Text   = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; ''; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.000 kHz </font>'; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
                app.taskStatus.Text      = '';
                app.taskRevisitTime.Text = '';
                app.lastGPS_color.Color  = [0.502 0.502 0.502];
                app.lastGPS_text.Text    = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            end
            drawnow

        end

        % Button pushed function: Button_Edit, Button_New, Button_Settings
        function ButtonPushed_OpenAuxiliarApp(app, event)
            
            switch event.Source
                case app.Button_Settings
                    h = app.auxiliarWin1;

                case {app.Button_New, app.Button_Edit}
                    h = app.auxiliarWin2;
            end

            if isempty(h)
%                 d = layoutFcn.modalWindow(app.UIFigure, 'ccTools.ProgressDialog');
                switch event.Source
                    case app.Button_Settings
                        app.auxiliarWin1 = auxApp.winSettings(app);
                    
                    case app.Button_New
                        app.auxiliarWin2 = auxApp.winAddTask(app, struct('type', 'new'));

                    case app.Button_Edit
                        if app.Table.Selection
                            app.auxiliarWin2 = auxApp.winAddTask(app, struct('type', 'edit', 'idx', app.Table.Selection));
                        end
                end
                drawnow nocallbacks
%                 delete(d)
            else
                figure(h.UIFigure)
            end

        end

        % Button pushed function: Button_Play
        function ButtonPushed_playTask(app, event)
            
            idx = app.Table.Selection;
            if idx && ismember(app.specObj(idx).Status, {'Cancelada', 'Erro', 'Concluída'})
                Timestamp = datetime('now');

                switch app.specObj(idx).Task.Script.Observation.Type
                    case 'Duration'
                        app.specObj(idx).Observation.BeginTime = Timestamp;
                        app.specObj(idx).Observation.EndTime   = Timestamp + seconds(app.specObj(idx).Task.Script.Observation.Duration);
    
                    case 'Time'
                        if strcmp(app.specObj(idx).Status, 'Concluída')
                            layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Uma tarefa no estado "Concluída" somente poderá ser executada novamente se o tipo do período de observação for "Duração" ou "Quantidade específica de amostras".');
                            return
                        end
    
                    case 'Samples'
                        app.specObj(idx).Observation.BeginTime = Timestamp;
                        app.specObj(idx).Observation.EndTime   = NaT;
                end

                app.specObj(idx).Status = 'Na fila';
                app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', 'Reincluída na fila a tarefa.');

                for ii = 1:numel(app.specObj(idx).Band)
                    app.specObj(idx).Band(ii).SyncModeRef   = -1;
                    app.specObj(idx).Band(ii).LastTimeStamp = [];
                    app.specObj(idx).Band(ii).Status        = true;
                end

                timer_RegularTaskCheck(app)
            end
            
        end

        % Button pushed function: Button_Del
        function ButtonPushed_delTask(app, event)
            
            idx = app.Table.Selection;
            if idx
                switch app.specObj(idx).Status
                    case 'Em andamento'
                        RegularTask_StatusTaskCheck(app, idx, 'DeleteButtonPushed');

                    otherwise
                        if ~app.Flag_running
                            app.specObj(idx) = [];
    
                            layoutFcn.tableBuilding(app, 1)
                            TreeSelectionChanged(app)
                        else
                            layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Uma tarefa poderá ser excluída, sendo eliminada da lista de tarefas, somente se não estiver sendo executada nenhuma tarefa.');
                        end
                end
            end

        end

        % Button pushed function: Button_log
        function ButtonPushed_logTask(app, event)

            idx = app.Table.Selection;
            if idx
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', Misc_logMsg(app, idx), 'msgTextAlign', 'left');
            end

        end

        % Button pushed function: Button_LayoutLeft, Button_LayoutRight, 
        % ...and 1 other component
        function ButtonPushed_layoutTool(app, event)
            
            switch event.Source
                case app.Button_LayoutTop
                    if app.upPanel_Grid.RowHeight{1}
                        app.upPanel_Grid.RowHeight{1} = 0;
                    else
                        app.upPanel_Grid.RowHeight{1} = 200;
                    end

                case app.Button_LayoutLeft
                    if app.upPanel_Grid.ColumnWidth{1}
                        app.upPanel_Grid.ColumnWidth{1} = 0;
                        app.upPanel_Grid.Padding(1)     = 0;
                    else
                        app.upPanel_Grid.ColumnWidth{1} = 256;
                        app.upPanel_Grid.Padding(1)     = 5;
                    end

                case app.Button_LayoutRight
                    if app.upPanel_Grid.ColumnWidth{5}
                        app.upPanel_Grid.ColumnWidth{5} = 0;
                        app.upPanel_Grid.Padding(3)     = 0;
                    else
                        app.upPanel_Grid.ColumnWidth{5} = 120;
                        app.upPanel_Grid.Padding(3)     = 5;
                    end
            end
            
        end

        % Value changed function: Button_Average, Button_MaxHold, 
        % ...and 1 other component
        function ButtonPushed_plotTraceMode(app, event)
            
            if ~app.Table.Selection || isempty(app.Tree.SelectedNodes) || app.Button_MaskPlot.Value
                return
            end

            ii = app.Table.Selection;
            jj = app.Tree.SelectedNodes.NodeData;

            if ~isempty(app.specObj(ii).Band(jj).Waterfall)
                idx = app.specObj(ii).Band(jj).Waterfall.idx;

                if idx
                    FreqStart = app.specObj(ii).Task.Script.Band(jj).FreqStart / 1e+6;
                    FreqStop  = app.specObj(ii).Task.Script.Band(jj).FreqStop  / 1e+6;
                    LevelUnit = app.specObj(ii).Task.Script.Band(jj).instrLevelUnit;

                    xArray    = linspace(FreqStart, FreqStop, app.specObj(ii).Band(jj).DataPoints);
                    newArray = app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:);

                    switch event.Source
                        case app.Button_MinHold
                            if app.Button_MinHold.Value
                                plotFcn.minHold(app, ii, jj, xArray, newArray)
                                plotFcn.DataTipModel(app.line_MinHold, LevelUnit)
                            else
                                delete(app.line_MinHold)
                                app.line_MinHold = [];
                            end

                        case app.Button_Average
                            if app.Button_Average.Value
                                plotFcn.Average(app, ii, jj, xArray, newArray)
                                plotFcn.DataTipModel(app.line_Average, LevelUnit)
                            else
                                delete(app.line_Average)
                                app.line_Average = [];
                            end

                        case app.Button_MaxHold
                            if app.Button_MaxHold.Value
                                plotFcn.maxHold(app, ii, jj, xArray, newArray)
                                plotFcn.DataTipModel(app.line_MaxHold, LevelUnit)
                            else
                                delete(app.line_MaxHold)
                                app.line_MaxHold = [];
                            end
                    end
                    drawnow nocallbacks
                end
            end

        end

        % Button pushed function: Button_Layout
        function ButtonPushed_plotLayout(app, event)
            
            switch app.plotLayout
                case 1; app.plotLayout = 2;
                case 2; app.plotLayout = 3;
                case 3; app.plotLayout = 1;
            end
            plotFcn.Layout(app)

        end

        % Value changed function: Button_MaskPlot
        function ButtonPushed_MaskPlotValueChanged(app, event)
            
            if ~app.Table.Selection || isempty(app.Tree.SelectedNodes)
                return
            end

            ii = app.Table.Selection;
            jj = app.Tree.SelectedNodes.NodeData;
    
            plotFcn.StartUp(app)
            plotFcn.Draw(app, ii, jj)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.upPanel_Grid = uigridlayout(app.mainGrid);
            app.Table = ccTools.Table(app.upPanel_Grid);
            app.Table.ColumnName = {'ID', 'Tarefa', 'Receptor', 'Inclusão', 'Início|Observação', 'Fim|Observação', 'Estado'};
            app.Table.FilterToolbar = false;
            app.Table.hFontSize = 11;
            app.Table.hCapitalLetter = true;
            app.Table.hClickable = false;
            app.Table.SelectionChangedFcn = createCallbackFcn(app, @TableSelectionChanged, true);
            app.Tree_Label = uilabel(app.upPanel_Grid);
            app.Tree_Label.Text = 'Faixa(s) de frequência:';
            app.Tree = uitree(app.upPanel_Grid);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.MetaData_Label = uilabel(app.upPanel_Grid);
            app.MetaData_Label.Text = 'Metadados:';
            app.MetaData_Panel = uipanel(app.upPanel_Grid);
            app.MetaData_Grid = uigridlayout(app.MetaData_Panel);
            app.MetaData = uihtml(app.MetaData_Grid);
            app.MetaData.HTMLSource = ' ';
            app.Plot_Panel = uipanel(app.upPanel_Grid);
            app.PlotTool_Grid = uigridlayout(app.upPanel_Grid);
            app.Button_MinHold = uibutton(app.PlotTool_Grid, 'state');
            app.Button_MinHold.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed_plotTraceMode, true);
            app.Button_MinHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MinHold.png');
            app.Button_MinHold.Text = '';
            app.Button_Average = uibutton(app.PlotTool_Grid, 'state');
            app.Button_Average.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed_plotTraceMode, true);
            app.Button_Average.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Average.png');
            app.Button_Average.Text = '';
            app.Button_MaxHold = uibutton(app.PlotTool_Grid, 'state');
            app.Button_MaxHold.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed_plotTraceMode, true);
            app.Button_MaxHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MaxHold.png');
            app.Button_MaxHold.Text = '';
            app.Button_Layout = uibutton(app.PlotTool_Grid, 'push');
            app.Button_Layout.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_plotLayout, true);
            app.Button_Layout.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Layers.png');
            app.Button_Layout.Text = '';
            app.Button_MaskPlot = uibutton(app.PlotTool_Grid, 'state');
            app.Button_MaskPlot.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed_MaskPlotValueChanged, true);
            app.Button_MaskPlot.Enable = 'off';
            app.Button_MaskPlot.Icon = 'LT_mask.png';
            app.Button_MaskPlot.Text = '';
            app.TaskInfo_Panel = uigridlayout(app.upPanel_Grid);
            app.Sweeps_Panel = uipanel(app.TaskInfo_Panel);
            app.Sweeps_Grid = uigridlayout(app.Sweeps_Panel);
            app.Sweeps_Grid.Tag = 'COLORLOCKED';
            app.Sweeps_REC = uiimage(app.Sweeps_Grid);
            app.Sweeps_REC.Visible = 'off';
            app.Sweeps_REC.ImageSource = fullfile(pathToMLAPP, 'Icons', 'REC.gif');
            app.Sweeps_Label = uilabel(app.Sweeps_Grid);
            app.Sweeps_Label.Text = {'VARREDURAS'; 'EM ARQUIVO'};
            app.Sweeps = uilabel(app.Sweeps_Grid);
            app.Sweeps.Text = '-1';
            app.errorCount_txt = uilabel(app.Sweeps_Grid);
            app.errorCount_txt.Visible = 'off';
            app.errorCount_txt.Text = '0';
            app.errorCount_img = uiimage(app.Sweeps_Grid);
            app.errorCount_img.Visible = 'off';
            app.errorCount_img.ImageSource = 'LT_redWarn.png';
            app.lastMask_Panel = uipanel(app.TaskInfo_Panel);
            app.lastMask_Grid = uigridlayout(app.lastMask_Panel);
            app.lastMask_Grid.Tag = 'COLORLOCKED';
            app.lastMask_label = uilabel(app.lastMask_Grid);
            app.lastMask_label.Text = {'MÁSCARA'; 'ESPECTRAL'};
            app.lastMask_text = uilabel(app.lastMask_Grid);
            app.lastMask_text.Interpreter = 'html';
            app.lastMask_text.Enable = 'off';
            app.lastMask_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; ''; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.000 kHz </font>'; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            app.lastGPS_Panel = uipanel(app.TaskInfo_Panel);
            app.lastGPS_Grid1 = uigridlayout(app.lastGPS_Panel);
            app.lastGPS_Grid1.Tag = 'COLORLOCKED';
            app.lastGPS_label = uilabel(app.lastGPS_Grid1);
            app.lastGPS_label.Text = {'COORDENADAS'; 'GEOGRÁFICAS'};
            app.lastGPS_text = uilabel(app.lastGPS_Grid1);
            app.lastGPS_text.Interpreter = 'html';
            app.lastGPS_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            app.lastGPS_Grid2 = uigridlayout(app.lastGPS_Grid1);
            app.lastGPS_color = uilamp(app.lastGPS_Grid2);
            app.errorCount_txt_2 = uilabel(app.lastGPS_Grid1);
            app.errorCount_txt_2.Visible = 'off';
            app.errorCount_txt_2.Text = '0';
            app.errorCount_img_2 = uiimage(app.lastGPS_Grid1);
            app.errorCount_img_2.Visible = 'off';
            app.errorCount_img_2.ImageSource = 'LT_redWarn.png';
            app.downPanel_Grid = uigridlayout(app.mainGrid);
            app.Button_New = uibutton(app.downPanel_Grid, 'push');
            app.Button_New.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_OpenAuxiliarApp, true);
            app.Button_New.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_AddFiles.png');
            app.Button_New.Tooltip = {''};
            app.Button_New.Text = '';
            app.Button_Edit = uibutton(app.downPanel_Grid, 'push');
            app.Button_Edit.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_OpenAuxiliarApp, true);
            app.Button_Edit.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_edit.png');
            app.Button_Edit.Enable = 'off';
            app.Button_Edit.Tooltip = {''};
            app.Button_Edit.Text = '';
            app.Button_Del = uibutton(app.downPanel_Grid, 'push');
            app.Button_Del.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_delTask, true);
            app.Button_Del.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_blackX.png');
            app.Button_Del.Enable = 'off';
            app.Button_Del.Tooltip = {''};
            app.Button_Del.Text = '';
            app.Button_Separator1 = uiimage(app.downPanel_Grid);
            app.Button_Separator1.Enable = 'off';
            app.Button_Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_LineV.png');
            app.Button_log = uibutton(app.downPanel_Grid, 'push');
            app.Button_log.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_logTask, true);
            app.Button_log.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_log.png');
            app.Button_log.Enable = 'off';
            app.Button_log.Text = '';
            app.taskStatus = uilabel(app.downPanel_Grid);
            app.taskStatus.Text = '';
            app.Button_LayoutTop = uibutton(app.downPanel_Grid, 'push');
            app.Button_LayoutTop.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_layoutTool, true);
            app.Button_LayoutTop.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_layoutUp.png');
            app.Button_LayoutTop.Text = '';
            app.Button_LayoutLeft = uibutton(app.downPanel_Grid, 'push');
            app.Button_LayoutLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_layoutTool, true);
            app.Button_LayoutLeft.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_layoutLeft.png');
            app.Button_LayoutLeft.Text = '';
            app.Button_LayoutRight = uibutton(app.downPanel_Grid, 'push');
            app.Button_LayoutRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_layoutTool, true);
            app.Button_LayoutRight.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_layoutRight.png');
            app.Button_LayoutRight.Text = '';
            app.Button_Separator2 = uiimage(app.downPanel_Grid);
            app.Button_Separator2.Enable = 'off';
            app.Button_Separator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_LineV.png');
            app.Button_Settings = uibutton(app.downPanel_Grid, 'push');
            app.Button_Settings.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_OpenAuxiliarApp, true);
            app.Button_Settings.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_settings.png');
            app.Button_Settings.Text = '';
            app.taskRevisitTime = uilabel(app.downPanel_Grid);
            app.taskRevisitTime.Text = '';
            app.Button_Separator1_2 = uiimage(app.downPanel_Grid);
            app.Button_Separator1_2.Enable = 'off';
            app.Button_Separator1_2.ImageSource = 'LT_LineV.png';
            app.Button_Play = uibutton(app.downPanel_Grid, 'push');
            app.Button_Play.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_playTask, true);
            app.Button_Play.Icon = 'LT_play.png';
            app.Button_Play.Enable = 'off';
            app.Button_Play.Tooltip = {''};
            app.Button_Play.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppColetaV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end