classdef winMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Tree                 matlab.ui.container.Tree
        FaixasdefrequnciarelacionadastarefaselecionadaLabel  matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        Image6               matlab.ui.control.Image
        task_NameEdit_2      matlab.ui.control.Button
        task_NameEdit        matlab.ui.control.Button
        task_scpiListEdit_2  matlab.ui.control.Button
        Image5               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        Image3               matlab.ui.control.Image
        Image4               matlab.ui.control.Image
        task_scpiListEdit    matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        table_Grid           matlab.ui.container.GridLayout
        Column5_2            matlab.ui.control.Label
        Column5              matlab.ui.control.Label
        Export_Grid          matlab.ui.container.GridLayout
        table_Separator      matlab.ui.control.Image
        table_Play           matlab.ui.control.Image
        table_Stop           matlab.ui.control.Image
        table_Edit           matlab.ui.control.Image
        table_New            matlab.ui.control.Image
        table_Del            matlab.ui.control.Image
        Column10             matlab.ui.control.Label
        Column8              matlab.ui.control.Label
        Column7              matlab.ui.control.Label
        Column3              matlab.ui.control.Label
        Column2              matlab.ui.control.Label
        Column1              matlab.ui.control.Label
        UITable              matlab.ui.control.Table
    end

    
    properties (Access = public)
        
        General
        RootFolder

        axes1
        axes2

        specObj      = specClass
        revisitObj
        timerObj

        instrHandles = table('Size', [0, 5],                                                    ...
                             'VariableTypes', {'string', 'string', 'string', 'cell', 'double'}, ...
                             'VariableNames', {'Type', 'IDN', 'Socket', 'Handle', 'Status'})
        udpPortArray = {}
        
        taskList
        scpiList
        instrInfo

        Flag_running = 0
        Flag_editing = 0

        auxWin1 = []                                                        % winTask: modal window
        auxWin2 = []
        auxWin3 = []

    end

    
    methods (Access = private)

        function startup_Layout(app)
            mainMonitor = get(0, 'MonitorPositions');

            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];
        end


        function startup_AxesCreation(app)
            t = tiledlayout(app.Panel, 3, 1, "Padding", "tight", "TileSpacing", "compact");

            % app.UIAxes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            set(app.axes1, Color = [0.94, .94, .94], ...
                           FontSize = 8)

            % app.UIAxes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 2;
            app.axes2.Layout.TileSpan  = [2,1];
            set(app.axes2, Color = [0.94, .94, .94], ...
                           FontSize = 8)
        end


        function startup_ConfigFiles(app)
            global appGeneral

            % app.General
            tempObj = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            app.General = tempObj;

            userpaths = {fullfile(getenv('USERPROFILE'), 'Documents'); ...
                         fullfile(getenv('USERPROFILE'), 'Downloads')};

            if ~isempty(app.General.userPath)
                userpaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userpaths):-1:1
                if ~isfolder(userpaths{ii})
                    userpaths(ii) = [];
                end
            end
            app.General.userPath = userpaths{end};

            if isempty(app.General.FindPeaks.THR)
                app.General.FindPeaks.THR = -inf;
            end
            appGeneral = app.General;


            % app.taskList
            taskTemp = jsondecode(fileread('taskList.json'));
            for ii = 1:numel(taskTemp)
                app.taskList{ii} = taskTemp(ii);
    
                if (app.taskList{ii}.Observation.Type == "Duration") & isempty(app.taskList{ii}.Observation.Duration)
                    app.taskList{ii}.Observation.Duration = inf;
                end
    
                if ~any([app.taskList{ii}.Band.Enable])
                    app.taskList{ii}.Band(1).Enable = 1;
                end
            end


            % app.scpiList
            tempList = jsondecode(fileread('scpiList.json'));
            for ii = 1:numel(tempList)
                tempList(ii).Parameters = jsonencode(tempList(ii).Parameters);
            end

            app.scpiList     = struct2table(tempList, 'AsArray', true);
            app.scpiList.idx = (1:numel(tempList))';
            app.scpiList     = movevars(app.scpiList, 'idx', 'Before', 1);


            % app.instrInfo            
            app.instrInfo = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'instrInfo.json'))));
        end


        function startup_Timer(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing",        ...
                                 "BusyMode",      "queue",               ...
                                 "StartDelay",    Timer_StartDelay(app), ...
                                 "Period",        900,                   ...
                                 "TimerFcn",      @(~,~)app.Timer_RegularTaskCheck);
            start(app.timerObj)
        end


        function StartDelay = Timer_StartDelay(app)
            StartDelay = 0;
            if mod(minute(datetime('now')), 15)
                StartDelay = (15 - mod(minute(datetime('now')), 15)) * 60;
            end
        end


        function Timer_RegularTaskCheck(app)
            if ~app.Flag_running
                Flag = [];
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii)
                        Flag(end+1) = true;
                    end
                end

                if ~isempty(Flag)
                    RegularTask_MainLoop(app)
                end
            end
        end


        function MessageBox(app, type, msg)
            appName = 'appColeta';

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, appName, 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'LT_info.png')
                case 'startup'; app.UIFigure.Visible = 1; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'CloseFcn', @(~,~)closeFcn(app));
            end
        end


        function uiTable_Building(app)
            Selection = app.UITable.Selection;
            if isempty(Selection); Selection = 1;
            end

            tempTable = table('Size', [0, 8],                                                                                    ...
                              'VariableTypes', {'double', 'string', 'string', 'string', 'string', 'string', 'string', 'string'}, ...
                              'VariableNames', {'ID', 'Name', 'Created', 'BeginTime', 'EndTime', 'Status', 'Receiver', 'Recursos'});

            
            for ii = 1:numel(app.specObj)
                Receiver = app.specObj(ii).hReceiver.Address;
                try
                    Receiver = sprintf('%s:%.0f', Receiver, app.specObj(ii).hReceiver.Port);
                catch
                end

                GPS     = app.specObj(ii).taskObj.General.Task.GPS.Type;
                Switch  = app.specObj(ii).taskObj.Antenna.Switch.Name;
                Antenna = strjoin(unique({app.specObj(ii).Band.Antenna}), '\n');

                Resources = struct('Receiver', Receiver, ...
                                   'GPS',      GPS,      ...
                                   'Switch',   Switch,   ...
                                   'Antenna',  Antenna);

                EndTime = '-';
                if ~isnat(app.specObj(ii).Observation.EndTime)
                    EndTime = datestr(app.specObj(ii).Observation.EndTime, 'dd/mm/yyyy HH:MM:SS');
                end

                tempTable(end+1,:) = {app.specObj(ii).ID,                        ...
                                      app.specObj(ii).taskObj.General.Task.Name, ...
                                      app.specObj(ii).Observation.Created,       ...
                                      datestr(app.specObj(ii).Observation.BeginTime, 'dd/mm/yyyy HH:MM:SS'), ...
                                      EndTime,                                   ...
                                      app.specObj(ii).Status,                    ...
                                      app.specObj(ii).hReceiver.UserData.IDN,    ...
                                      jsonencode(Resources)};
            end

            app.UITable.Data      = tempTable;
            app.UITable.Selection = Selection;

            UITableSelectionChanged(app)
        end


        function uiTable_ToolbarLayout(app)


        end


        function uiTree_Building(app)            
            delete(app.Tree.Children);
            
            idx = app.UITable.Selection;
            for ii = 1:numel(app.specObj(idx).Band)
                Antenna = app.specObj(idx).taskObj.General.Task.Band(ii).instrAntenna;
                if ~isempty(Antenna)
                    AntennaName = sprintf('(%s)', Antenna);
                end

                uitreenode(app.Tree, 'Text', sprintf('ID %d: %.3f - %.3f MHz %s',                                     ...
                                                     app.specObj(idx).taskObj.General.Task.Band(ii).ThreadID,         ...
                                                     app.specObj(idx).taskObj.General.Task.Band(ii).FreqStart / 1e+6, ...
                                                     app.specObj(idx).taskObj.General.Task.Band(ii).FreqStop  / 1e+6, ...
                                                     Antenna),                                                        ...
                                     'NodeData', ii);
            end
            
            app.Tree.SelectedNodes = app.Tree.Children(1);
            TreeSelectionChanged(app)            
        end


        function RegularTask_MainLoop(app)
            stop(app.timerObj)
            
            app.Flag_running = 1;
            app.Flag_editing = 1;
            
            while app.Flag_running
                if app.Flag_editing
                    app.revisitObj = task_RevisitFactors(app.specObj);
                    if isempty(app.revisitObj.GlobalRevisitTime)
                        app.Flag_running = 0;
                        break
                    end
                    
                    nn = 0;
                    app.Flag_editing = 0;
                end

                sweepTic = tic;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii)
                        app.Flag_editing = 1;
                        break
                    end

                    if app.specObj(ii).Status ~= "Em andamento..."
                        continue
                    end
                    
                    hReceiver  = app.specObj(ii).hReceiver;
                    hStreaming = app.specObj(ii).hStreaming;
                    hGPS       = app.specObj(ii).hGPS;
                    hSwitch    = app.specObj(ii).hSwitch;

                    nBands = numel(app.specObj(ii).Band);    
                    for jj = 0:nBands
                        if mod(nn, app.revisitObj.Band(ii).RevisitFactors(jj+1)) || app.revisitObj.Band(ii).RevisitFactors(jj+1) == -1
                            continue
                        end
    
                        if jj == 0
                            app.specObj(ii).lastGPS = RegularTask_gpsData(app, ii, hReceiver, hGPS);

                        else
                            app.specObj(ii) = RFlookBinLib.CheckFile(app.specObj(ii), jj);
    
                            try
                                % ANTENNA SWITCH (IF APPLICABLE)
                                RegularTask_AntennaSwitch(app, ii, hSwitch)

                                % RECEIVER RECONFIGURATION (IF APPLICABLE)
                                if nBands > 1
                                    writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Config);
                                    pause(.001)
                                    
                                    if ~isempty(app.specObj(ii).Band(jj).scpiSet_Att)
                                        writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Att);
                                    end
                                end

                                newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                    
                                attFactor = -1;
                                if ~isempty(app.specObj(ii).SCPI.scpiGet_Att)
                                    attFactor = str2double(writeread(hReceiver, app.specObj(ii).SCPI.scpiGet_Att));
                                end

                                % WATERFALL MATRIX
                                idx = app.specObj(ii).Band(jj).Waterfall.idx + 1;
                                if idx > app.General.Waterfall.Depth; idx = 1;
                                end

                                app.specObj(ii).Band(jj).Waterfall.idx = idx;
                                app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:) = newArray;

                                % PLOT (IF APPLICABLE)
                                if (app.UITable.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                    RegularTask_PlotUpdate(app, ii, jj, newArray)
                                end
                                
                                % SPECTRAL MASK ANALYSIS (IF APPLICABLE)
                                if ~isempty(app.specObj(ii).Band(jj).Mask)
                                    smoothedArray = smoothdata(newArray);
                                    
                                    if any(smoothedArray - app.specInfo.mask{jj}.array > 0, 'all')
                                        app.specObj(ii).Band(jj).Mask.Count     = app.specObj(ii).Band(jj).Mask.Count + 1;
                                        app.specObj(ii).Band(jj).Mask.MainPeaks = task_FindPeaks(app.specObj(ii), jj, smoothedArray, app.General.FindPeaks);
                                    else
                                        continue
                                    end                                        
                                end
                                        
                                RFlookBinLib.EditFile(app.specObj(ii), jj, newArray, attFactor)
                                app.specObj(ii).Band(jj).File.WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples + 1;
    
                            catch ME
                                % CONTADOR DE ERROS - SE CHEGAR A 10
                                % SUCESSIVOS (PRECISA ZERAR SE DEU CERTO), FAZ-SE UMA CHECAGEM NAS
                                % COISAS.
                                getReport(ME)
                            end
                        end
                    end
                end
                
                nn = nn+1;
                pause(max([.1, app.revisitObj.GlobalRevisitTime-toc(sweepTic)]))
            end

            set(app.timerObj, 'StartDelay', Timer_StartDelay(app))
            start(app.timerObj)
        end


        function Flag = RegularTask_StatusTaskCheck(app, idx)
            Flag = false;
            switch app.specObj(idx).Status        
                case 'Em andamento...'
                    if app.specObj(idx).Observation.EndTime < datetime('now')
                        Flag = true;
                        app.specObj(idx).Status = 'Concluída';

                    else
                        if app.specObj(idx).taskObj.General.Task.Observation.Type == "Samples"
                            nBands   = numel(app.specObj(idx).Band);
                            tempFlag = zeros(1, nBands, 'logical');
                            
                            for ii = 1:numel(app.specObj(idx).Band)
                                if app.specObj(idx).Band(ii).File.WritedSamples == app.specObj(idx).taskObj.General.Task.Band(ii).instrObservationSamples
                                    app.specObj(idx) = RFlookBinLib.CloseFile(app.specObj(idx), ii);
                                    tempFlag(ii) = true;
                                else
                                    tempFlag(ii) = false;
                                end
                            end

                            if all(tempFlag)
                                Flag = true;
                                app.specObj(idx).Status = 'Concluída';
                            elseif any(tempFlag)
                                Flag = true;
                            end
                        end
                    end

                case {'Na fila...', 'Erro'}
                    Timestamp = datetime('now');                    
                    if app.specObj(idx).Status == "Erro" 
                        if ~mod(minute(Timestamp), 15) 
                            if isnat(app.specObj(idx).Observation.StartUp)
                                app.specObj(idx).Observation.StartUp = Timestamp;
                            end
    
                            StartUp = app.specObj(idx).Observation.StartUp;
                            if isequal([year(Timestamp), month(Timestamp), day(Timestamp), hour(Timestamp), minute(Timestamp)], ...
                                    [year(StartUp), month(StartUp), day(StartUp), hour(StartUp), minute(StartUp)])
                                return
                            end
                        else
                            return
                        end
                    end

                    if app.specObj(idx).Observation.BeginTime < datetime('now')
                        switch app.specObj(idx).taskObj.General.Task.Observation.Type
                            case {'Duration', 'Time'}
                                if isnat(app.specObj(idx).Observation.EndTime) || (app.specObj(idx).Observation.EndTime > datetime('now'))
                                    Flag = true;
                                end

                            case 'Samples'
                                Flag = true;
                        end
                    end

                    if Flag
                        try
                            RegularTask_StartUp(app, idx);
                            app.specObj(idx).Status = 'Em andamento...';
                        catch ME
                            app.specObj(idx).LOG(end+1) = struct('type', 'error', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', ME.message);
                            app.specObj(idx).Status = 'Erro';
                            Flag = false;
                        end
                    end
            end

            if Flag
                uiTable_Building(app)
                drawnow nocallbacks
            end
        end


        function gpsData = RegularTask_gpsData(app, idx, hReceiver, hGPS)
            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);

            try
                switch app.specObj(idx).taskObj.General.Task.GPS.Type
                    case 'Built-in'; gpsData = task_gpsDecode(hReceiver);
                    case 'External'; gpsData = task_gpsReader(hGPS, 1);
                end
            catch
            end
        end
        
        
        function RegularTask_AntennaSwitch(app, idx, hSwitch)
            % PENDENTE!!!
            % Se não conseguir configurar adequadamente, retorna um erro,
            % indicando o que foi tentado, o que obtido de resposta do
            % comutador ect.
        end


        function newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming)
            Timeout = 10;
            Flag_success = false;

            recTic = tic;
            t1 = toc(recTic);        
            
            switch app.specObj(ii).taskObj.General.SCPI.connectFlag{1}
                case 1                                                      % Spectrum analyzers (R&S, KeySight, Tektronix, Anritsu)
                    while t1 < Timeout
                        try
                            writeline(hReceiver, app.specObj(ii).SCPI.scpiGet_Data);
                            newArray = readbinblock(hReceiver, 'single');
                                                        
                            if numel(newArray) == app.specObj(ii).Band(jj).DataPoints
                                if strcmp(app.specObj(ii).taskObj.Receiver.Sync, 'Continuous Sweep')
                                    SyncModeRef = sum(newArray);

                                    if SyncModeRef ~= app.specObj(ii).Band(jj).SyncModeRef
                                        app.specObj(ii).Band(jj).SyncModeRef = SyncModeRef;
                                    else
                                        continue
                                    end                                    
                                end

                                % PENDENTE CRIAR CAMPO NO TASK >> 
                                if app.specObj(ii).taskObj.General.Task.instrFlipArray
                                    newArray = flip(newArray);
                                end

                                Flag_success = true;
                                break
                            end
    
                        catch
                        end
                        t1 = toc(recTic);
                    end
                    
                case 2                                                      % R&S EB500
                    lastwarn('')
        
                    newArray = zeros(1, app.specObj(ii).Band(jj).DataPoints, 'single');
                    RegularTask_EB500_Streaming(app, 'Open', hReceiver, hStreaming)
        
                    specDatagram = [];
                    while t1 < Timeout
                        nDatagrams   = 2*app.specObj(ii).Band(jj).Datagrams-1;
                        specDatagram = [specDatagram, read(hStreaming, nDatagrams)];
        
                        [warnMsg, warnID] = lastwarn;
                        if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                            error(struct('message', warnMsg, 'identifier', warnID))
                        end

                        % Sorted datagrams
                        DatagramsID = zeros(numel(specDatagram), 1);        
                        for kk = 1:numel(specDatagram)
                            specDatagram(kk).Data = uint8(specDatagram(kk).Data);
        
                            DatagramID_low  = double(typecast(specDatagram(kk).Data(10:-1: 9), 'uint16'));
                            DatagramID_high = double(typecast(specDatagram(kk).Data(12:-1:11), 'uint16')).*2^16;
                            DatagramsID(kk) = DatagramID_low + DatagramID_high;
                        end

                        if ~issorted(DatagramsID)
                            [DatagramsID, idxSort] = sort(DatagramsID);
                            specDatagram = specDatagram(idxSort);
                        end

                        % Array
                        udpFlag  = 0;
                        Points   = 0;

                        for kk = 1:numel(specDatagram)
                            if udpFlag && (DatagramsID(kk) ~= DatagramsID(kk-1)+1)
                                udpFlag  = 0;
                                Points   = 0;
                                continue
                            end

                            MagicNumber = typecast(specDatagram(kk).Data( 4:-1: 1), 'uint32');
                            DataSize    = typecast(specDatagram(kk).Data(16:-1:13), 'uint32');

                            FreqStart   = double(typecast(specDatagram(kk).Data(32:-1:29), 'uint32')) + double(typecast(specDatagram(kk).Data(44:-1:41), 'uint32')) * 2^32;
                            FreqStop    = double(typecast(specDatagram(kk).Data(36:-1:33), 'uint32')) + double(typecast(specDatagram(kk).Data(48:-1:45), 'uint32')) * 2^32;
                            DataPoints  = (FreqStop - FreqStart)/double(typecast(specDatagram(kk).Data(40:-1:37), 'uint32')) + 1;
        
                            if (MagicNumber == 963072)                                                      && ...
                                    (DataSize   == numel(specDatagram(kk).Data))                            && ...
                                    (FreqStart  == app.specObj(ii).taskObj.General.Task.Band(jj).FreqStart) && ...
                                    (FreqStop   == app.specObj(ii).taskObj.General.Task.Band(jj).FreqStop)  && ...
                                    (DataPoints == app.specObj(ii).Band(jj).DataPoints)
                                idx1   = Points + 1;
                                Points = Points + typecast(specDatagram(kk).Data(22:-1:21), 'uint16');
                                idx2   = Points;
            
                                if typecast(specDatagram(kk).Data(end:-1:end-1), 'uint16') == 2000
                                    if Points == DataPoints+1
                                        newArray(idx1:idx2-1) = single(flip(typecast(specDatagram(kk).Data((end-2):-1:81), 'int16')))./10;
                                        Flag_success = true;
                                        break
                                    else
                                        udpFlag = 0;
                                        Points  = 0;
                                    end
                                else
                                    udpFlag = 1;
                                    newArray(idx1:idx2) = single(flip(typecast(specDatagram(kk).Data(end:-1:81), 'int16')))./10;                                    
                                end
                            end
                        end

                        if Flag_success
                            break
                        end
                        t1 = toc(recTic);
                    end
                    RegularTask_EB500_Streaming(app, 'Close', hReceiver, hStreaming)
            end
            flush(hReceiver)
            
            if ~Flag_success
                error('Não foi lido corretamente o vetor de nível do receptor dentro do tempo limite (10 segundos).')
            end            
        end


        function RegularTask_PlotUpdate(app, ii, jj, newArray)
            FreqStart  = app.specObj(ii).taskObj.General.Task.Band(jj).FreqStart / 1e+6;
            FreqStop   = app.specObj(ii).taskObj.General.Task.Band(jj).FreqStop  / 1e+6;
            DataPoints = app.specObj(ii).Band(jj).DataPoints;

            Tag = sprintf('%.3f - %.3f (%.0f points)', FreqStart, FreqStop, DataPoints);

            tempObj = findobj(app.axes1, 'Tag', Tag);
            if isempty(tempObj)
                plot(app.axes1, linspace(FreqStart, FreqStop, DataPoints), newArray, 'Tag', Tag)
            else
                tempObj.YData = newArray;
            end
            drawnow

            % PENDENTE ATUALIAÇÃO DO WATERFALL
        end


        function RegularTask_EB500_Datagrams(app, idx, hReceiver, hStreaming)
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'off', 'Interpreter', 'html', ...
                                            'Cancelable', 'on', 'CancelText', 'Cancelar',  ...
                                            'Message', 'Em andamento a estimativa do número de datagramas que representam um único traço...');

            Timeout = 10;
            lastwarn('')

            for ii = 1:numel(app.specObj(idx).Band)
                set(d, 'Value', 0, 'Title', sprintf('ID %.0f: %.3f - %.3f MHz', app.specObj(idx).taskObj.General.Task.Band(ii).ThreadID,         ...
                                                                                app.specObj(idx).taskObj.General.Task.Band(ii).FreqStart / 1e+6, ...
                                                                                app.specObj(idx).taskObj.General.Task.Band(ii).FreqStop  / 1e+6))

                writeline(hReceiver, app.specObj(idx).Band(ii).scpiSet_Config);
                
                RegularTask_EB500_Streaming(app, 'Open', hReceiver, hStreaming)

                udpTic = tic;
                t = toc(udpTic);
                while t < Timeout
                    if d.CancelRequested
                        RegularTask_EB500_Streaming(app, 'Close', hReceiver, hStreaming)
                        error('Não identificada estimativa do número de datagramas que representam um único traço... operação cancelada pelo usuário.')
                    end

                    value = hStreaming.NumDatagramsAvailable/50;
                    if value > 1; value = 1;
                    end
                    set(d, 'Value', value)

                    if hStreaming.NumDatagramsAvailable > 50
                        break
                    end
                    t = toc(udpTic);
                end
                specDatagram = read(hStreaming, hStreaming.NumDatagramsAvailable);
                RegularTask_EB500_Streaming(app, 'Close', hReceiver, hStreaming)                

                nDatagrams  = 0;
                nTerminator = 0;
                for jj = 1:numel(specDatagram)
                    specDatagram(jj).Data = uint8(specDatagram(jj).Data);

                    MagicNumber = typecast(specDatagram(jj).Data( 4:-1: 1), 'uint32');
                    DataSize    = typecast(specDatagram(jj).Data(16:-1:13), 'uint32');

                    FreqStart   = double(typecast(specDatagram(jj).Data(32:-1:29), 'uint32')) + double(typecast(specDatagram(jj).Data(44:-1:41), 'uint32')) * 2^32;
                    FreqStop    = double(typecast(specDatagram(jj).Data(36:-1:33), 'uint32')) + double(typecast(specDatagram(jj).Data(48:-1:45), 'uint32')) * 2^32;
                    DataPoints  = (FreqStop - FreqStart)/double(typecast(specDatagram(jj).Data(40:-1:37), 'uint32')) + 1;

                    if (MagicNumber == 963072) && (DataSize == numel(specDatagram(jj).Data)) && (FreqStart == app.specObj(idx).taskObj.General.Task.Band(ii).FreqStart) && (FreqStop == app.specObj(idx).taskObj.General.Task.Band(ii).FreqStop) && (DataPoints == app.specObj(idx).Band(ii).DataPoints)
                        nDatagrams = nDatagrams + 1;
                        if typecast(specDatagram(jj).Data(end:-1:end-1), 'uint16') == 2000
                            nTerminator = nTerminator + 1;
                        end
                    end
                end
                flush(hStreaming)

                if nTerminator
                    app.specObj(idx).Band(ii).Datagrams = round(nDatagrams/nTerminator);
                else
                    error('Não identificada estimativa do número de datagramas que representam um único traço... É possível que haja algum bloqueio de <i>firewall</i>.')
                end
            end
        end


        function RegularTask_EB500_Streaming(app, Type, hReceiver, hStreaming)
            Localhost_publicIP = hReceiver.UserData.ClientIP;

            switch Type
                case 'Open'
                    flush(hStreaming)

                    writeline(hReceiver, sprintf('TRACE:UDP:TAG:ON "%s", %.0f, PSCAN',                Localhost_publicIP, app.General.udpPort));
                    writeline(hReceiver, sprintf('TRACE:UDP:FLAG:ON "%s", %.0f, "VOLTage:AC", "OPT"', Localhost_publicIP, app.General.udpPort));

                case 'Close'
                    writeline(hReceiver, sprintf('TRACE:UDP:TAG:OFF "%s", %.0f, PSCAN',                Localhost_publicIP, app.General.udpPort));
                    writeline(hReceiver, sprintf('TRACE:UDP:FLAG:OFF "%s", %.0f, "VOLTage:AC", "OPT"', Localhost_publicIP, app.General.udpPort));
                    writeline(hReceiver, sprintf('TRACE:UDP:DEL "%s", %.0f',                           Localhost_publicIP, app.General.udpPort));
                    
                    flush(hStreaming)
            end
        end


        function RegularTask_StartUp(app, idx)
            taskObj  = app.specObj(idx).taskObj;
            baseName = sprintf('appColeta_%s', datestr(now, 'yymmdd_THHMMSS'));

            % HANDLES
            hReceiver  = app.specObj(idx).hReceiver;
            hStreaming = app.specObj(idx).hStreaming;
            hGPS       = app.specObj(idx).hGPS;
            hSwitch    = app.specObj(idx).hSwitch;
            
            IDN = replace(deblank(writeread(hReceiver, '*IDN?')), '"', '');
            if ~strcmp(hReceiver.UserData.IDN, IDN)
                error('Não se trata do instrumento originalmente inserido na tarefa.\nEsperado: %s\nIdentificado: %s', hReceiver.UserData.IDN, IDN)
            end

            if ~isempty(hStreaming)
                if contains(hReceiver.UserData.IDN, 'EB500')
                    RegularTask_EB500_Datagrams(app, idx, hReceiver, hStreaming)
                end
            end

            if ~isempty(hGPS)
                % TESTAR GPS EXTERNO
            end

            if ~isempty(hSwitch)
                % TESTAR COMUTADOR DE ANTENAS
            end

            for ii = 1:numel(app.specObj(idx).Band)
                ThreadID = taskObj.General.Task.Band(ii).ThreadID;

                % MASK
                app.specObj(idx).Band(ii).Mask = [];
                if strcmp(taskObj.General.Type, 'Rompimento de Máscara Espectral') && taskObj.General.Task.Band(ii).Trigger
                    maskText = fileread(taskObj.General.Maskfile);
                    maskInfo = mask_FileRead(maskText);

                    app.specObj(idx).Band(ii).Mask = struct('Array', mask_ArrayConstructor(maskInfo, taskObj.General.Task.Band(ii)), 'Count', 0, 'MainPeaks', '');
                    app.specObj(idx).LOG(end+1)    = struct('type', 'mask', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', sprintf('ID %.0f\n%s', ThreadID, jsonencode(maskInfo.Table)));
                end


                % FILE
                app.specObj(idx).Band(ii).File = struct('Fileversion', app.General.File.Version,              ...
                                                        'Basename', sprintf('%s_ID%.0f', baseName, ThreadID), ...
                                                        'Filecount', 0, 'WritedSamples', 0, 'CurrentFile', []);

                [app.specObj(idx).Band(ii).File.Filecount, ...
                    app.specObj(idx).Band(ii).File.CurrentFile] = RFlookBinLib.OpenFile(app.specObj(idx), ii);

                logMsg = sprintf(['ID: %.0f\n'             ...
                                  'scpiSet_Config: "%s"\n' ...
                                  'scpiSet_Att: "%s"\n'    ...
                                  'scpiSet_Answer: "%s"\n' ...
                                  'Filename (base): %s\n'  ...
                                  'AlocatedSamples: %.0f'], ThreadID,                             ...
                                                            app.specObj(idx).Band(ii).scpiSet_Config, ...
                                                            app.specObj(idx).Band(ii).scpiSet_Att,    ...
                                                            app.specObj(idx).Band(ii).scpiSet_Answer, ...
                                                            app.specObj(idx).Band(ii).File.Basename,  ...
                                                            app.specObj(idx).Band(ii).File.CurrentFile.AlocatedSamples);

                app.specObj(idx).LOG(end+1) = struct('type', 'startup', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', logMsg);


                % WATERFALL MATRIX
                WaterfallDepth = app.General.Waterfall.Depth;   
                DataPoints     = taskObj.General.Task.Band(ii).instrDataPoints;

                switch taskObj.General.Task.Band(ii).instrLevelUnit
                    case 'dBm';            refLevel = -120;
                    case {'dBµV', 'dBμV'}; refLevel = -13;
                end
                
                if taskObj.General.Task.Observation.Type == "Samples"
                    WaterfallDepth  = min([WaterfallDepth,  taskObj.General.Task.Band(ii).instrObservationSamples]);
                end
                app.specObj(idx).Band(ii).Waterfall = struct('idx', 0, 'Matrix', refLevel .* ones(WaterfallDepth, DataPoints, 'single'));
            end      
        end


        function logMsg = Misc_logMsg(app, idx)
            logMsg = '';
            if ~isempty(app.specObj(idx).LOG)
                logTable = struct2table(app.specObj(idx).LOG);
                logMsg   = strjoin("<b>" + logTable.time + " - " + upper(logTable.type) + "</b>" + newline + logTable.msg, '\n\n');
            end
        end

    end

    
    methods (Access = public)

        function Misc_WindowBackground(app, Type)
            switch Type
                case 'Create'
                    uihtml(app.UIFigure, "HTMLSource", '<html><body style="background-color: rgb(0, 0 ,0, 0.45);"></body></html>', ...
                                         "Position", [1, 1, app.UIFigure.Position(3:4)], ...
                                         "Tag", "WindowBackground");
                case 'Delete'
                    delete(findobj(app.UIFigure, "Tag", "WindowBackground"))
            end
        end

        
        function winAux_AddOrEditTask(app, taskObj)
            delete(app.auxWin1.UIFigure)
            app.auxWin1 = [];
            Misc_WindowBackground(app, 'Delete')

            [app.specObj, idx] = Fcn_AddTask(app.specObj, taskObj);
            if ~isempty(app.specObj(idx).LOG)
                MessageBox(app, 'info', Misc_logMsg(app, idx))
            end

            Timer_RegularTaskCheck(app)
            
            uiTable_Building(app)
            drawnow nocallbacks
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.Visible = 0;            
            appName = 'appColeta_Task';
                        
            % PATH SEARCH
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                    Flag = 0;
                    MessageBox(app, 'startup', 'Não identificado local de instalação do <i>app</i>.')
                end
                
            else
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), appName);
                
                if ~isempty(prjPath) & strcmp(prjPath.Name, appName)
                    app.RootFolder = char(prjPath.RootFolder);
                elseif isfolder(appPath)
                    app.RootFolder = appPath;                    
                else
                    Flag = 0;
                    MessageBox(app, 'startup', 'Não identificado local de instalação do <i>app</i>.')
                end
            end

            % INITIALIZATION            
            if Flag
                try
                    % <LOG>
                    diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))
                    
                    diary on
                    fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now,'dd/mm/yyyy HH:MM:SS')))
                    % </LOG>

                    warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                    warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                    warning('off', 'MATLAB:structOnObject')

                    startup_Layout(app)
                    startup_AxesCreation(app)
                    startup_ConfigFiles(app)
                    startup_Timer(app)

                catch ME
                    fprintf('%s\n', jsonencode(ME))
                    MessageBox(app, 'startup', getReport(ME))
                end

                app.UIFigure.Visible = 1;
                drawnow nocallbacks
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.auxWin3)

            delete(timerfindall)

            delete(app)
            
        end

        % Callback function: UITable
        function UITableSelectionChanged(app, event)

            uiTree_Building(app)
            drawnow nocallbacks
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            cla(app.axes1)
            cla(app.axes2)

            ii = app.UITable.Selection;
            jj = app.Tree.SelectedNodes.NodeData;

            if ~isempty(app.specObj(ii).Band(jj).Waterfall)
                idx = app.specObj(ii).Band(jj).Waterfall.idx;

                if idx
                    newArray = app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:);
                    RegularTask_PlotUpdate(app, ii, jj, newArray)
                end
            end
            
        end

        % Image clicked function: table_Del, table_Edit, table_New, 
        % table_Play, table_Stop
        function TableToolbarCallbacks(app, event)
            
            switch event.Source
                case app.table_Play
                    if ~isempty(app.specObj)
                        RegularTask_MainLoop(app)
                    end

                case app.table_Stop
                    app.Flag_running = 0;                    

                case {app.table_New, app.table_Edit}
                        d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');
        
                        switch event.Source
                            case app.table_New;  tempVar = struct('type', 'new');
                            case app.table_Edit; tempVar = struct('type', 'edit', 'idx', app.UITable.Selection);
                        end
        
                        app.auxWin1 = winTask(app, tempVar);
                        drawnow

                        Misc_WindowBackground(app, 'Create')
                        delete(d)

                case app.table_Del
                    idx = app.UITable.Selection;

                    if ismember(app.specObj(idx).Status, ["Na fila...", "Em andamento...", "Erro"])
                        if app.specObj(idx).Status == "Em andamento..."
                            app.Flag_editing = 1;
                        end
                        app.specObj(idx).Status = "Cancelada";
                    end
            end

        end

        % Button pushed function: task_NameEdit
        function task_NameEditButtonPushed(app, event)
            
            if isempty(app.auxWin2)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');

                app.auxWin2 = winTaskEdit(app);
                drawnow nocallbacks

                delete(d)                
            else
                figure(app.auxWin2.UIFigure)
            end

        end

        % Button pushed function: task_scpiListEdit
        function task_scpiListEditButtonPushed(app, event)
            
            if isempty(app.auxWin3)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');

                app.auxWin3 = winScpiEdit(app);
                drawnow nocallbacks

                delete(d)
            else
                figure(app.auxWin3.UIFigure)
            end

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            pause(1)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Tarefas';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.table_Grid = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.table_Grid);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.Column1 = uilabel(app.table_Grid);
            app.Column1.Text = {'  ID'; '  '};
            app.Column2 = uilabel(app.table_Grid);
            app.Column2.Text = {'  Tarefa'; '  '};
            app.Column3 = uilabel(app.table_Grid);
            app.Column3.Text = {'   Inclusão'; '  '};
            app.Column7 = uilabel(app.table_Grid);
            app.Column7.Text = {'  Início'; '  Observação'};
            app.Column8 = uilabel(app.table_Grid);
            app.Column8.Text = {'  Fim'; '  Observação'};
            app.Column10 = uilabel(app.table_Grid);
            app.Column10.Text = {'  Estado'; '  '};
            app.Export_Grid = uigridlayout(app.table_Grid);
            app.table_Del = uiimage(app.Export_Grid);
            app.table_Del.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Del.Enable = 'off';
            app.table_Del.Tooltip = {'Excluir tarefa'};
            app.table_Del.ImageSource = 'LT_redX.png';
            app.table_New = uiimage(app.Export_Grid);
            app.table_New.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_New.Tooltip = {'Nova tarefa'};
            app.table_New.ImageSource = 'LT_AddFiles.png';
            app.table_Edit = uiimage(app.Export_Grid);
            app.table_Edit.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Edit.Enable = 'off';
            app.table_Edit.Tooltip = {'Editar tarefa'};
            app.table_Edit.ImageSource = 'LT_edit.png';
            app.table_Stop = uiimage(app.Export_Grid);
            app.table_Stop.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Stop.Enable = 'off';
            app.table_Stop.Tooltip = {'Parar tarefa'};
            app.table_Stop.ImageSource = 'LT_stop.png';
            app.table_Play = uiimage(app.Export_Grid);
            app.table_Play.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Play.Enable = 'off';
            app.table_Play.Tooltip = {'Executar tarefa'};
            app.table_Play.ImageSource = 'LT_play.png';
            app.table_Separator = uiimage(app.Export_Grid);
            app.table_Separator.ImageSource = 'LT_LineV.png';
            app.Column5 = uilabel(app.table_Grid);
            app.Column5.Text = {'  Receptor'; '  '};
            app.Column5_2 = uilabel(app.table_Grid);
            app.Column5_2.Text = {'  Recursos'; '  '};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.task_scpiListEdit = uibutton(app.GridLayout3, 'push');
            app.task_scpiListEdit.ButtonPushedFcn = createCallbackFcn(app, @task_scpiListEditButtonPushed, true);
            app.task_scpiListEdit.Tag = 'task_Editable';
            app.task_scpiListEdit.Icon = 'LT_connect.png';
            app.task_scpiListEdit.Tooltip = {'Editar lista de instrumentos '; '(Analisador de espectro, receptor e GPS)'};
            app.task_scpiListEdit.Text = '';
            app.Image4 = uiimage(app.GridLayout3);
            app.Image4.ImageSource = 'tb_maxHold.gif';
            app.Image3 = uiimage(app.GridLayout3);
            app.Image3.ImageSource = 'tb_meanHold.gif';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'LR_Persistance.png';
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = 'tb_minHold.gif';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'LT_LineV.png';
            app.task_scpiListEdit_2 = uibutton(app.GridLayout3, 'push');
            app.task_scpiListEdit_2.Tag = 'task_Editable';
            app.task_scpiListEdit_2.Icon = 'tb_waterfall.gif';
            app.task_scpiListEdit_2.Tooltip = {'Editar lista de instrumentos '; '(Analisador de espectro, receptor e GPS)'};
            app.task_scpiListEdit_2.Text = '';
            app.task_NameEdit = uibutton(app.GridLayout3, 'push');
            app.task_NameEdit.ButtonPushedFcn = createCallbackFcn(app, @task_NameEditButtonPushed, true);
            app.task_NameEdit.Tag = 'task_Editable';
            app.task_NameEdit.Icon = 'LT_edit.png';
            app.task_NameEdit.Tooltip = {'Parametrização'; 'Tarefa SCPI'};
            app.task_NameEdit.Text = '';
            app.task_NameEdit_2 = uibutton(app.GridLayout3, 'push');
            app.task_NameEdit_2.Tag = 'task_Editable';
            app.task_NameEdit_2.Icon = 'LT_edit.png';
            app.task_NameEdit_2.Tooltip = {'Parametrização'; 'Tarefa SCPI'};
            app.task_NameEdit_2.Text = '';
            app.Image6 = uiimage(app.GridLayout3);
            app.Image6.ImageSource = 'LT_LineV.png';
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel = uilabel(app.GridLayout);
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel.Text = 'Faixa(s) de frequência relacionada(s) à tarefa selecionada:';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end