classdef winTask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout2                matlab.ui.container.GridLayout
        task_Grid                  matlab.ui.container.GridLayout
        task_GridB2                matlab.ui.container.GridLayout
        task_PanelLabel            matlab.ui.control.Label
        task_imgRefresh            matlab.ui.control.Image
        task_Panel_22              matlab.ui.container.Panel
        task_Grid22                matlab.ui.container.GridLayout
        task_Detector2Label_2      matlab.ui.control.Label
        task_Detector2_2           matlab.ui.control.DropDown
        task_SelectivityLabel      matlab.ui.control.Label
        task_Selectivity           matlab.ui.control.DropDown
        task_IntegrationTimeLabel  matlab.ui.control.Label
        task_IntegrationTime       matlab.ui.control.NumericEditField
        task_LevelUnit2Label       matlab.ui.control.Label
        task_LevelUnit2            matlab.ui.control.EditField
        task_Detector2Label        matlab.ui.control.Label
        task_Detector2             matlab.ui.control.DropDown
        task_PreampLabel           matlab.ui.control.Label
        task_Preamp                matlab.ui.control.DropDown
        task_attFactorLabel        matlab.ui.control.Label
        task_attFactor             matlab.ui.control.DropDown
        task_attModeLabel          matlab.ui.control.Label
        task_attMode               matlab.ui.control.DropDown
        task_Resolution2Label      matlab.ui.control.Label
        task_Resolution2           matlab.ui.control.DropDown
        task_DataPointsLabel       matlab.ui.control.Label
        task_DataPoints2           matlab.ui.control.NumericEditField
        task_DataPoints1           matlab.ui.control.Spinner
        task_StepWidth2Label       matlab.ui.control.Label
        task_StepWidth2            matlab.ui.control.DropDown
        task_StepWidth1            matlab.ui.control.NumericEditField
        task_TreeLabel             matlab.ui.control.Label
        mainInfo_Text              matlab.ui.control.TextArea
        mainInfo_TextLabel         matlab.ui.control.Label
        task_Tree                  matlab.ui.container.Tree
        task_ButtonGrid            matlab.ui.container.GridLayout
        OKButton                   matlab.ui.control.Button
        mainPanel                  matlab.ui.container.Panel
        mainPanel_Grid             matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        task_Antenna               matlab.ui.control.DropDown
        task_AntennaLabel          matlab.ui.control.Label
        task_antennaPanel          matlab.ui.container.Panel
        task_antennaGrid           matlab.ui.container.GridLayout
        task_ElevationLabel        matlab.ui.control.Label
        task_Elevation             matlab.ui.control.NumericEditField
        task_AzimuthLabel          matlab.ui.control.Label
        task_Azimuth               matlab.ui.control.NumericEditField
        task_HeightLabel           matlab.ui.control.Label
        task_Height                matlab.ui.control.NumericEditField
        task_gpsListGrid_2         matlab.ui.container.GridLayout
        task_Antenna_2             matlab.ui.control.DropDown
        task_AntennaLabel_2        matlab.ui.control.Label
        task_gpsConnectivity_2     matlab.ui.control.Button
        GridLayout4                matlab.ui.container.GridLayout
        task_gpsListGrid           matlab.ui.container.GridLayout
        task_gpsConnectivity       matlab.ui.control.Button
        task_gpsList               matlab.ui.control.DropDown
        task_gpsListLabel          matlab.ui.control.Label
        task_gpsPanel              matlab.ui.container.Panel
        task_gpsGrid               matlab.ui.container.GridLayout
        task_manualLongitude       matlab.ui.control.NumericEditField
        task_manualLongitudeLabel  matlab.ui.control.Label
        task_manualLatitude        matlab.ui.control.NumericEditField
        task_manualLatitudeLabel   matlab.ui.control.Label
        task_gpsRevisitTime        matlab.ui.control.NumericEditField
        task_gpsRevisitTimeLabel   matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        Task_SyncRef               matlab.ui.control.DropDown
        Task_SyncRefLabel          matlab.ui.control.Label
        Task_ResetCommand          matlab.ui.control.DropDown
        Task_ResetCommandLabel     matlab.ui.control.Label
        task_scpiListGrid          matlab.ui.container.GridLayout
        task_scpiConnectivity      matlab.ui.control.Button
        task_scpiListLabel         matlab.ui.control.Label
        task_scpiList              matlab.ui.control.DropDown
        Tab4_Grid                  matlab.ui.container.GridLayout
        Tab4_Image                 matlab.ui.control.Image
        Tab4_Title                 matlab.ui.control.Label
        Tab3_Grid                  matlab.ui.container.GridLayout
        Tab3_Image                 matlab.ui.control.Image
        Tab3_Title                 matlab.ui.control.Label
        Tab2_Grid                  matlab.ui.container.GridLayout
        Tab2_Image                 matlab.ui.control.Image
        Tab2_Title                 matlab.ui.control.Label
        Tab1_Panel                 matlab.ui.container.GridLayout
        task_TypeLabel_3           matlab.ui.control.Label
        task_Type_3                matlab.ui.control.DropDown
        Panel                      matlab.ui.container.Panel
        task_GridA3                matlab.ui.container.GridLayout
        task_TypeLabel_2           matlab.ui.control.Label
        task_Type_2                matlab.ui.control.DropDown
        Report_Grid4               matlab.ui.container.GridLayout
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Report_Spinner4            matlab.ui.control.Spinner
        Report_Spinner3            matlab.ui.control.Spinner
        Report_DatePicker2         matlab.ui.control.DatePicker
        Report_Spinner2            matlab.ui.control.Spinner
        Report_Spinner1            matlab.ui.control.Spinner
        Report_DatePicker1         matlab.ui.control.DatePicker
        task_DurationUnit          matlab.ui.control.DropDown
        task_Duration              matlab.ui.control.NumericEditField
        PerododeobservaoLabel      matlab.ui.control.Label
        task_GridA2                matlab.ui.container.GridLayout
        task_TypeLabel             matlab.ui.control.Label
        task_Type                  matlab.ui.control.DropDown
        task_maskFile              matlab.ui.control.Button
        task_addMaskFile           matlab.ui.control.Button
        task_NameLabel             matlab.ui.control.Label
        task_Name                  matlab.ui.control.DropDown
        Tab1_Grid                  matlab.ui.container.GridLayout
        Tab1_Image                 matlab.ui.control.Image
        Tab1_Title                 matlab.ui.control.Label
    end


    properties (Access = public)
        
        Version
        RootFolder = [];

        % Variáveis lidas no início da aplicação:
        instrInfo
        
        scpiList
        rfeyeList

        taskList
        bandList        
        
        EB500Map

        % Instrumentos e sockets:
        Instrument    = [];
        udpPortArray  = {};
        udpPort       = [];
        gpsInstrument = [];
        instrTimeout
        ftpObj  = [];

        % Related to live plot
        traceInfo     = struct('SelectedNode', 0, ...
                               'Mode', [0, 0, 0], ...
                               'plotLayout', 0);

        fig = [];

        tb_refreshView = [];
        tb_interaction = [];
        tb_layout      = [];
        tb_minHold     = [];
        tb_meanHold    = [];
        tb_maxHold     = [];
        tb_waterfall   = [];
        tb_mask        = [];
        tb_rec         = [];

        axes1 = [];
        axes2 = [];

        line_ClrWrite = [];
        line_minHold  = [];
        line_Average  = [];
        line_maxHold  = [];
        surface_wFall = [];
        cBar1         = [];

        RestoreView = [];

        taskFlag = 0;
        recFlag  = 0;
        axesFlag = 0;
        axesFlagType = '';
        
        x

        timeInfo   = [];
        specInfo   = [];
        specMatrix = [];
        gpsInfo    = [];

        General
        
        auxWin1 = [];           % winSettings
        auxWin2 = [];           % winTaskEdit
        auxWin3 = [];           % winSCPIEdit
        auxWin4 = [];           % winRFeyeEdit
        auxWin5 = [];           % winMonitor

    end


    methods (Access = private)

        function task_Layout(app)
            
            if app.menu_Button2.Value
                if ismember(app.taskFlag, [1, 2])
                    set(findall(groot, 'Parent', app.menu_Grid2, 'Type', 'uistatebutton'), 'Enable', 0)

                    set(findobj(groot, 'Tag', 'task_Editable'), 'Enable', 0)
                    set(app.task_Grid22.Children, 'Enable', 0)

                    set(app.task_LogFile, 'Text', '', 'Tag', '')
                    if app.taskFlag == 1
                        set(app.task_Button1, 'Enable', 1, 'Icon', 'DT_stop.png')
                        set(app.task_Button2, 'Enable', 0)
                        
                        app.tool_Task.Text   = 'WPT';
                        app.tool_REC.Visible = 0;

                        set(app.tb_rec, 'Visible', 1, 'State', 0, 'CData', Fcn_ToolbarIcon(app, 'rec_play'))

                    else
                        set(app.task_Button1, 'Enable', 0)
                        set(app.task_Button2, 'Enable', 1, 'Icon', 'DT_stop.png')

                        app.tool_Task.Text   = 'RT';
                        app.tool_REC.Visible = 1;

                        set(app.tb_rec, 'State', 0, 'Visible', 0)
                    end

                    app.tb_mask.Visible = 0;

                else
                    set(findall(groot, 'Parent', app.menu_Grid2, 'Type', 'uistatebutton'), 'Enable', 1)
                    set(findobj(groot, 'Tag', 'task_Editable'), 'Enable', 1)
                    set(app.task_Grid22.Children, 'Enable', 1)

                    app.task_Tree.Enable = 1;
                    
                    task_gpsListValueChanged(app)
                    task_AntennaValueChanged(app)

                    set(app.task_Button1, 'Enable',  1, 'Icon', 'DT_play.png')
                    set(app.task_Button2, 'Enable',  1, 'Icon', 'DT_play.png')
                    
                    app.tool_Task.Text   = '';
                    app.tool_REC.Visible = 0;

                    if app.tb_rec.State
                        set(app.tb_rec, 'State', 0, 'Visible', 0)
                    end
                end

            elseif app.menu_Button3.Value
                if app.taskFlag == 3
                    set(findall(groot, 'Parent', app.menu_Grid2, 'Type', 'uistatebutton'), 'Enable', 0)

                    % Painel 1:
                    app.logger_NodeList.Enable    = 0;
                    app.logger_Conectivity.Enable = 0;

                    set(app.logger_Panel1a_Grid.Children,      'Enable', 0)
                    set(app.logger_udpDatagrams_Grid.Children, 'Visible', 1)

                    % Painel 2:                    
                    app.logger_ThreadID.Value    = -1;
                    app.logger_Description.Value = '';
                    app.logger_FreqStart.Value   = -1;
                    app.logger_FreqStop.Value    = -1;
                    app.logger_StepWidth.Value   = -1;
                    app.logger_Resolution.Value  = -1;
                    app.logger_Operation.Value   = '';
                    app.logger_DataPoints.Value  = -1;
                    app.logger_LevelUnit.Value   = '';
                    
                    app.logger_Tree.Enable = 1;
                    set(app.logger_TextInfo, 'Enable', 1, 'Value', '')

                    app.logger_EditRFeyeList.Enable = 0;
                    app.logger_Monitor.Enable = 0;
                    
                    set(app.logger_ButtonPlay, 'Icon', 'DT_stop.png')
                    app.tool_Task.Text = 'LT';
                    
                    set(app.tb_rec, 'Visible', 1, 'State', 0, 'CData', Fcn_ToolbarIcon(app, 'rec_play'))

                else
                    set(findall(groot, 'Parent', app.menu_Grid2, 'Type', 'uistatebutton'), 'Enable', 1)

                    % Painel 1:
                    app.logger_NodeList.Enable    = 1;
                    app.logger_Conectivity.Enable = 1;

                    set(app.logger_Panel1a_Grid.Children, 'Enable', 1)
                    set(app.logger_AvailableDatagrams, 'Visible', 0, 'Value', 0)
                    app.logger_AvailableDatagramsLabel.Visible = 0;
                    app.logger_PauseLabel.Visible = 0;
                    app.logger_Pause.Visible      = 0;

                    % Painel 2:                    
                    set(app.logger_ButtonPlay, 'Icon', 'DT_play.png')
                    app.tool_Task.Text = '';

                    app.logger_EditRFeyeList.Enable = 1;
                    app.logger_Monitor.Enable = 1;

                    app.task_Button1.Icon = 'DT_play.png';

                    set(app.tb_rec, 'Visible', 0, 'State', 0, 'CData', Fcn_ToolbarIcon(app, 'rec_play'))
                end
            end

        end


        function task_TreeBuilding(app, ind1)

            delete(app.task_Tree.Children);

            for ii = 1:numel(app.taskList{ind1}.Band)
                str = sprintf('ThreadID %d: %.3f - %.3f MHz',                  ...
                                app.taskList{ind1}.Band(ii).ThreadID,          ...
                                app.taskList{ind1}.Band(ii).FreqStart ./ 1e+6, ...
                                app.taskList{ind1}.Band(ii).FreqStop  ./ 1e+6);

                node = uitreenode(app.task_Tree, 'Text', str, 'NodeData', ii);

                if app.task_Type.Value == "Monitoração espectral - Rompimento de máscara espectral"
                    switch app.taskList{ind1}.Band(ii).Trigger
                        case 0; node.Icon = "LT_playback.png";
                        case 1; node.Icon = "LT_OCC.png";
                    end
                end
            end

            if ~app.taskFlag; app.task_Tree.SelectedNodes = app.task_Tree.Children(1);
            else;             app.task_Tree.SelectedNodes = app.task_Tree.Children(app.specMatrix.Index(4));
            end

        end


        function task_ParametersValues(app, ind1, ind2)

            app.task_ID.Value                = app.taskList{ind1}.Band(ind2).ThreadID;
            app.task_Description.Value       = app.taskList{ind1}.Band(ind2).Description;
            app.task_FreqStart.Value         = app.taskList{ind1}.Band(ind2).FreqStart / 1e+6;
            app.task_FreqStop.Value          = app.taskList{ind1}.Band(ind2).FreqStop  / 1e+6;
            app.task_RFMode.Value            = app.taskList{ind1}.Band(ind2).RFMode;
            app.task_StepWidth.Value         = app.taskList{ind1}.Band(ind2).StepWidth  / 1e+3;
            app.task_Resolution.Value        = app.taskList{ind1}.Band(ind2).Resolution / 1e+3;
            app.task_LevelUnit.Value         = app.taskList{ind1}.Band(ind2).LevelUnit;
            app.task_TraceMode.Value         = app.taskList{ind1}.Band(ind2).TraceMode;
            app.task_Detector.Value          = app.taskList{ind1}.Band(ind2).Detector;
            app.task_RevisitTime.Value       = app.taskList{ind1}.Band(ind2).RevisitTime;
            app.task_IntegrationFactor.Value = app.taskList{ind1}.Band(ind2).IntegrationFactor;

        end


        function task_ParametersEditables_Visibility(app)

            ind = find(app.instrInfo.Name == string(extractBetween(app.task_scpiList.Value, ': ', ' -')), 1);
            switch app.instrInfo.connectFlag{ind}
                case 1
                    app.task_Grid22.RowHeight(2:3)    = {22,0};
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set1'), 'Enable', 1)
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set2'), 'Enable', 0)

                    app.task_PreampLabel.Visible      = 1;
                    app.task_Preamp.Visible           = 1;

                    app.task_Resolution2.Layout.Row   = 2;
                    
                    app.task_SelectivityLabel.Visible = 0;
                    app.task_Selectivity.Visible      = 0;

                    app.task_IntegrationTimeLabel.Visible = 0;
                    app.task_IntegrationTime.Visible      = 0;

                case 2
                    app.task_Grid22.RowHeight(2:3)    = {0,22};
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set1'), 'Enable', 0)
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set2'), 'Enable', 1)

                    app.task_PreampLabel.Visible      = 0;
                    app.task_Preamp.Visible           = 0;

                    app.task_Resolution2.Layout.Row   = 3;

                    app.task_SelectivityLabel.Visible = 1;
                    app.task_Selectivity.Visible      = 1;

                    app.task_IntegrationTimeLabel.Visible = 1;
                    switch app.task_TraceMode.Value
                        case 'ClearWrite'
                            set(app.task_IntegrationTime, 'Visible', 1, 'Enable', 0)
                        otherwise
                            set(app.task_IntegrationTime, 'Visible', 1, 'Enable', 1)
                    end
            end

        end


        function instrSettings = task_ParametersEditables_GetValues(app, ind1, ind2)

            instrSettings = struct('StepWidth_Items',   [], 'StepWidth',       [], ...
                                   'DataPoints_Limits', [], 'DataPoints',      [], ...
                                   'Resolution_Items',  [], 'Resolution',      [], ...
                                   'Selectivity_Items', [], 'Selectivity',     [], ...
                                   'SensitivityMode',   [], 'Preamp',          [], ...
                                   'AttMode',           [], 'AttFactor_Items', [], ...
                                   'AttFactor',         [], 'LevelUnit',       [], ...
                                   'Detector_Items',    [], 'IntegrationTime', []);

            span = app.taskList{ind1}.Band(ind2).FreqStop - app.taskList{ind1}.Band(ind2).FreqStart;

            ind0 = find(app.instrInfo.Name == string(extractBetween(app.task_scpiList.Value,   ': ', ' -')), 1);
            
            instrSettings.AttMode         = 'Auto';
            instrSettings.AttFactor_Items = strsplit(app.instrInfo.Attenuation_Values{ind0}, ',');
            instrSettings.AttFactor       = instrSettings.AttFactor_Items{1};
            instrSettings.Detector_Items  = strsplit(app.instrInfo.Detector_Items{ind0}, ',');

            switch app.instrInfo.connectFlag{ind0}
                case 1
                    instrSettings.Resolution_Items = strsplit(app.instrInfo.Resolution_Values{ind0}, ',');

                    rbwValues = [];
                    for ii = 1:numel(instrSettings.Resolution_Items)
                        rbwValues = [rbwValues, str2double(extractBefore(instrSettings.Resolution_Items{ii}, ' kHz')).*1000];
                    end

                    instrSettings.DataPoints_Limits = app.instrInfo.DataPoints_Limits{ind0};

                    DataPoints = round(span/app.taskList{ind1}.Band(ind2).StepWidth + 1);
                    if     DataPoints < instrSettings.DataPoints_Limits(1); instrSettings.DataPoints = instrSettings.DataPoints_Limits(1);
                    elseif DataPoints > instrSettings.DataPoints_Limits(2); instrSettings.DataPoints = fix(instrSettings.DataPoints_Limits(2));
                    else;                                                   instrSettings.DataPoints = DataPoints;
                    end
                    instrSettings.StepWidth = span/(instrSettings.DataPoints - 1);

                    rbwIndex  = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                    instrSettings.Resolution = instrSettings.Resolution_Items{rbwIndex};

                    instrSettings.Selectivity     = '';

                    instrSettings.SensitivityMode = '0';
                    instrSettings.Preamp          = 'On';

                    instrSettings.LevelUnit       = app.taskList{ind1}.Band(ind2).LevelUnit;
                    if contains(app.task_scpiList.Value, 'N9344C')
                        instrSettings.LevelUnit   = 'dBm';
                    end

                    switch app.taskList{ind1}.Band(ind2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode         = 'Manual';
                            instrSettings.SensitivityMode = '1';
                        case 'Low Distortion'
                            instrSettings.Preamp          = 'Off';
                    end

                case 2                                                                                              % EB500
                    instrSettings.StepWidth_Items   = strsplit(app.instrInfo.StepWidth_Values{ind0}, ',');
                    instrSettings.Selectivity_Items = {'Normal', 'Narrow', 'Sharp'};

                    stepValues = [];
                    for ii = 1:length(instrSettings.StepWidth_Items)
                        stepValues = [stepValues, str2double(extractBefore(instrSettings.StepWidth_Items{ii}, ' kHz')).*1000];
                    end

                    stepIndex  = find(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth) == min(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth)));
                    instrSettings.StepWidth  = instrSettings.StepWidth_Items{stepIndex};

                    instrSettings.DataPoints = span/(str2double(extractBefore(instrSettings.StepWidth, ' kHz'))*1000) + 1;

                    rbwValues = [];
                    rbwItems  = {};
                    for ii = 1:3
                        rbwValues = [rbwValues, app.EB500Map{instrSettings.StepWidth, ii}];
                        rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
                    end
                    instrSettings.Resolution_Items = rbwItems;

                    rbwIndex = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                    instrSettings.Resolution  = instrSettings.Resolution_Items{rbwIndex};
                    instrSettings.Selectivity = app.task_Selectivity.Items{rbwIndex};

                    instrSettings.SensitivityMode = 'NORM';
                    instrSettings.Preamp          = 'Off';
                    instrSettings.LevelUnit       = 'dBμV';

                    switch app.taskList{ind1}.Band(ind2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode = 'Manual';
                        case 'Low Distortion'
                            instrSettings.SensitivityMode = 'LOWD';
                    end

                    switch app.taskList{ind1}.Band(ind2).TraceMode
                        case 'ClearWrite'
                            instrSettings.IntegrationTime = 0;
                        otherwise
                            instrSettings.IntegrationTime = 10 * app.taskList{ind1}.Band(ind2).IntegrationFactor;
                    end
            end

        end


        function task_ParametersEditables_SaveValues(app, ind1, ind2)

            instrSettings = task_ParametersEditables_GetValues(app, ind1, ind2);

            app.taskList{ind1}.Band(ind2).instrStepWidth_Items   = instrSettings.StepWidth_Items;
            app.taskList{ind1}.Band(ind2).instrStepWidth         = instrSettings.StepWidth;

            app.taskList{ind1}.Band(ind2).instrDataPoints_Limits = instrSettings.DataPoints_Limits;
            app.taskList{ind1}.Band(ind2).instrDataPoints        = instrSettings.DataPoints;

            app.taskList{ind1}.Band(ind2).instrResolution_Items  = instrSettings.Resolution_Items;
            app.taskList{ind1}.Band(ind2).instrResolution        = instrSettings.Resolution;
            
            app.taskList{ind1}.Band(ind2).instrSelectivity       = instrSettings.Selectivity;
            app.taskList{ind1}.Band(ind2).instrSensitivityMode   = instrSettings.SensitivityMode;
            
            app.taskList{ind1}.Band(ind2).instrPreamp            = instrSettings.Preamp;
            app.taskList{ind1}.Band(ind2).instrAttMode           = instrSettings.AttMode;
            app.taskList{ind1}.Band(ind2).instrAttFactor_Items   = instrSettings.AttFactor_Items;
            app.taskList{ind1}.Band(ind2).instrAttFactor         = instrSettings.AttFactor;

            app.taskList{ind1}.Band(ind2).instrDetector_Items    = instrSettings.Detector_Items;
            indDetector = find(strcmp(app.taskList{ind1}.Band(ind2).instrDetector_Items, app.taskList{ind1}.Band(ind2).Detector), 1);
            if isempty(indDetector)
                indDetector = 1;
            end
            app.taskList{ind1}.Band(ind2).instrDetector        = instrSettings.Detector_Items{indDetector};
            app.taskList{ind1}.Band(ind2).instrLevelUnit       = instrSettings.LevelUnit;
            app.taskList{ind1}.Band(ind2).instrIntegrationTime = instrSettings.IntegrationTime;

            app.taskList{ind1}.Band(ind2).EditedFlag = 0;
            app.task_imgRefresh.Visible              = 0;

        end


        function task_ParametersEditables_ShowValues(app, ind1, ind2)

            if ~app.taskList{ind1}.Band(ind2).EditedFlag
                task_ParametersEditables_SaveValues(app, ind1, ind2)
            else
                if ~app.taskFlag
                    app.task_imgRefresh.Visible = 1;
                end
            end

            idx = find(cellfun(@(x) contains(app.task_scpiList.Value, x), app.instrInfo.Tag), 1);
            switch app.instrInfo.connectFlag{idx}
                case 1
                    if fix(diff(app.instrInfo.DataPoints_Limits{idx}))
                        set(app.task_StepWidth1,  'Editable', 1, ...
                                                  'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                        set(app.task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                                  'Value',  app.taskList{ind1}.Band(ind2).instrDataPoints)
                    else
                        set(app.task_StepWidth1,  'Editable', 0, ...
                                                  'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                        set(app.task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                                  'Value',  app.instrInfo.DataPoints_Limits{idx}(1))
                    end

                case 2
                    set(app.task_StepWidth2, 'Items', app.taskList{ind1}.Band(ind2).instrStepWidth_Items, ...
                                             'Value', app.taskList{ind1}.Band(ind2).instrStepWidth)
                    app.task_DataPoints2.Value     = app.taskList{ind1}.Band(ind2).instrDataPoints;
                    app.task_Selectivity.Value     = app.taskList{ind1}.Band(ind2).instrSelectivity;
                    app.task_IntegrationTime.Value = app.taskList{ind1}.Band(ind2).instrIntegrationTime;
            end

            set(app.task_Resolution2, 'Items', app.taskList{ind1}.Band(ind2).instrResolution_Items, ...
                                      'Value', app.taskList{ind1}.Band(ind2).instrResolution)

            app.task_Preamp.Value     = app.taskList{ind1}.Band(ind2).instrPreamp;
            app.task_attMode.Value    = app.taskList{ind1}.Band(ind2).instrAttMode;
            app.task_LevelUnit2.Value = app.taskList{ind1}.Band(ind2).instrLevelUnit;

            set(app.task_attFactor, 'Items', app.taskList{ind1}.Band(ind2).instrAttFactor_Items, ...
                                    'Value', app.taskList{ind1}.Band(ind2).instrAttFactor)

            set(app.task_Detector2, 'Items', app.taskList{ind1}.Band(ind2).instrDetector_Items, ...
                                    'Value', app.taskList{ind1}.Band(ind2).instrDetector)

            if strcmp(app.task_attMode.Value, 'Auto')
                app.task_attFactorLabel.Visible = 0;
                app.task_attFactor.Visible      = 0;
            else
                app.task_attFactorLabel.Visible = 1;
                app.task_attFactor.Visible      = 1;
            end

        end


        function [ind1, ind2] = task_WarnVisibility(app)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            ind2 = app.task_Tree.SelectedNodes.NodeData;

            app.taskList{ind1}.Band(ind2).EditedFlag = 1;
            app.task_imgRefresh.Visible = 1;

            app.specInfo = [];

        end


        function Parameters = task_ParametersField(app, idx)

            Parameters = jsondecode(app.scpiList.Parameters{idx});

            if ~isfield(Parameters, 'IP')
                Parameters.IP = '';
            end

            if ~isfield(Parameters, 'Port')
                Parameters.Port = '';
            end

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            PanelTab_SelectionChanged(app, struct('Source', app.Tab1_Image))

        end

        % Value changed function: task_Name
        function task_TaskSelectionChanged(app, event)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);

            Duration_sec = app.taskList{ind1}.Duration;
            if Duration_sec >= 3600
                app.task_Duration.Value     = Duration_sec ./ 3600;
                app.task_DurationUnit.Value = 'hr';
            else
                app.task_Duration.Value     = Duration_sec ./ 60;
                app.task_DurationUnit.Value = 'min';
            end

            app.task_BitsPerPoint.Value     = sprintf('%d bits', app.taskList{ind1}.BitsPerSample);
            
            task_TreeBuilding(app, ind1)
            task_TreeSelectionChanged(app)
            
            if ~isempty(app.task_scpiList.Items)
                app.task_Button1.Enable     = 1;
                app.task_Button2.Enable     = 1;
                
            else
                app.task_Button1.Enable     = 0;
                app.task_Button2.Enable     = 0;
            end

            app.specInfo = [];
            task_scpiListValueChanged(app)

        end

        % Value changed function: task_Type
        function task_TypeValueChanged(app, event)

            switch app.task_Type.Value
                case "Monitoração espectral - Rompimento de máscara espectral"
                    app.task_GridA2.ColumnWidth(2:3) = {22, 22};
                    if ~isempty(app.task_maskFile.Tooltip{1})
                        app.task_maskFile.Enable = 1;
                    end

                otherwise
                    app.task_GridA2.ColumnWidth(2:3) = {0, 0};
                    app.task_maskFile.Enable = 0;
            end

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            task_TreeBuilding(app, ind1)
            
        end

        % Button pushed function: task_addMaskFile
        function task_addMaskFileButtonPushed(app, event)
            
            [Filename, Filepath] = uigetfile({'*.csv', '(*.csv)'}, ...
                                              'Selecione um arquivo de máscara espectral', 'MultiSelect', 'off');
            figure(app.UIFigure)
            
            if Filename
                try
                    mask_FileRead(app, fileread(fullfile(Filepath, Filename)));
                    set(app.task_maskFile, 'Enable', 1, 'Tooltip', {fullfile(Filepath, Filename)})

                catch ME
                    fprintf('%s\n', jsonencode(ME))
                    MessageBox(app, 'error', getReport(ME))

                    set(app.task_maskFile, 'Enable', 0, 'Tooltip', {''})                    
                end
            end

        end

        % Button pushed function: task_maskFile
        function task_maskFileButtonPushed(app, event)
            
            try
                msg = fileread(app.task_maskFile.Tooltip{1});
                MessageBox(app, 'info', msg)

            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))
            end

        end

        % Selection changed function: task_Tree
        function task_TreeSelectionChanged(app, event)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            ind2 = app.task_Tree.SelectedNodes.NodeData;

            task_ParametersValues(app, ind1, ind2)

            if ~isempty(app.task_scpiList.Items)
                task_ParametersEditables_ShowValues(app, ind1, ind2)
            end

            if app.taskFlag
                app.axesFlagType = 'SelectedNode';
                app.axesFlag     = 1;
            end

        end

        % Value changed function: task_scpiList
        function task_scpiListValueChanged(app, event)
            
            InitialGPS             = app.task_gpsList.Value;
            app.task_gpsList.Items = {'ID 0: Manual'};

            indGPSs = intersect(find(strcmp(app.scpiList.Family, 'GPS')), find(app.scpiList.Enable));
            for ii = 1:numel(indGPSs)
                Parameters = task_ParametersField(app, ii);

                switch app.scpiList.Type{ii}
                    case 'Serial'
                        Socket = Parameters.Port;

                    case 'TCPIP Socket'
                        Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
                end

                app.task_gpsList.Items = [app.task_gpsList.Items, sprintf('ID %d: %s - %s', indGPSs(ii), app.scpiList.Name{indGPSs(ii)}, Socket)];
            end

            % Lista de GPSs (incluso instrumento que possua embarcado nele um GPS).
            indFind1 = find(app.instrInfo.Name == string(extractBetween(app.task_scpiList.Value, ': ', ' -')), 1);

            app.Task_SyncRef.Items = strsplit(app.instrInfo.SyncOptions{indFind1}, ',');

            if ~isempty(app.instrInfo.scpiGPS{indFind1})
                app.task_gpsList.Items = sort([app.task_gpsList.Items, app.task_scpiList.Value]);
                app.task_gpsList.Value = app.task_scpiList.Value;
                
            elseif find(strcmp(app.task_gpsList.Items, InitialGPS), 1)
                app.task_gpsList.Value = InitialGPS;
            end
            task_gpsListValueChanged(app)

            % Peculiaridades dos receptores.
            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            for ii = 1:numel(app.taskList{ind1}.Band)
                task_ParametersEditables_SaveValues(app, ind1, ii)
            end

            if isempty(app.task_Tree.SelectedNodes); ind2 = 1;
            else;                                    ind2 = app.task_Tree.SelectedNodes.NodeData;
            end

            % Controla visibilidade dos elementos passíveis de alteração
            % das faixas a monitorar.
            task_ParametersEditables_Visibility(app)
            task_ParametersEditables_ShowValues(app, ind1, ind2)

        end

        % Button pushed function: task_scpiConnectivity
        function task_scpiConnectivityButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html'); 
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            try
                idx1 = str2double(char(extractBetween(app.task_scpiList.Value, 'ID', ':')));
                
                Parameters    = task_ParametersField(app, idx1);
                instrSelected = struct('Type', app.scpiList.Type{idx1}, 'IP', Parameters.IP, 'Port', str2double(Parameters.Port));
                scpiNode      = connect_scpi(instrSelected, []);

                if ~isempty(scpiNode.UserData.IDN)
                    scpiName = string(extractBetween(app.task_scpiList.Value, ': ', ' -'));
                    idx2 = find(app.instrInfo.Name == scpiName, 1);

                    if contains(scpiNode.UserData.IDN, app.instrInfo.Tag{idx2})
                        msg = sprintf('Conectado ao <b>%s</b>.', scpiNode.UserData.IDN);
                        MessageBox(app, 'info', msg)
                    else
                        msg = sprintf('Esperava-se instrumento da família <b>%s</b>, mas identificado <b>%s</b>.', scpiName, scpiNode.UserData.IDN);
                        MessageBox(app, 'error', msg)
                    end
                else
                    msg = 'Não recebida resposta ao comando "*IDN?".';
                    MessageBox(app, 'error', msg)
                end

                delete(scpiNode)
                clear scpiNode

            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))
            end

            delete(d)

        end

        % Value changed function: task_gpsList
        function task_gpsListValueChanged(app, event)

            if strcmp(app.task_gpsList.Value, 'ID 0: Manual')
                set(app.task_gpsGrid.Children, 'Enable', 1)                
                app.task_gpsRevisitTime.Enable = 0;

            else
                set(app.task_gpsGrid.Children, 'Enable', 0)                                
                app.task_gpsRevisitTime.Enable = 1;
                
                app.task_gpsRevisitTimeLabel.Enable  = 1;
                app.task_manualLatitudeLabel.Enable  = 1;
                app.task_manualLongitudeLabel.Enable = 1;
            end

        end

        % Button pushed function: task_gpsConnectivity
        function task_gpsConnectivityButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html'); 
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            try
                if strcmp(app.task_scpiList.Value, app.task_gpsList.Value)
                    idx = str2double(char(extractBetween(app.task_scpiList.Value, 'ID', ':')));

                    Parameters    = task_ParametersField(app, idx);
                    instrSelected = struct('Type', app.scpiList.Type{idx}, 'IP', Parameters.IP, 'Port', str2double(Parameters.Port));
                    scpiNode      = connect_scpi(instrSelected, []);
                    gps           = Task_gpsDecode(scpiNode);

                    delete(scpiNode)
                    clear scpiNode

                elseif strcmp(app.task_gpsList.Value, 'ID 0: Manual')
                    gps = struct('Status', 1, 'Latitude', app.task_manualLatitude.Value, 'Longitude', app.task_manualLongitude.Value, 'TimeStamp', []);

                else
                    RegularTask_gps_Aux1(app)
                    gps = app.gpsInstrument.UserData;
                    RegularTask_gps_Aux2(app)
                end
    
                if gps.Status
                    [City, Distance] = geo_FindCity(gps);

                    msg = sprintf('Coordenadas geográficas distam <b>%.1f km</b> da sede do município <b>%s</b>.', Distance, City);
                    
                    if ~isempty(gps.TimeStamp)
                        msg = sprintf('%s\n\nHorário: %s (UTC)', msg, datestr(gps.TimeStamp, 'dd/mm/yyyy HH:MM:SS'));
                    end
                    MessageBox(app, 'info', msg)
                    
                    app.task_manualLatitude.Value  = round(gps.Latitude,  6);
                    app.task_manualLongitude.Value = round(gps.Longitude, 6);

                else
                    error('<b>Não recebida informação válida do instrumento acerca das coordenadas geográficas do local de monitoração.</b>\n%s', jsonencode(gps))
                end

            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))
            end

            delete(d)

        end

        % Value changed function: task_Antenna
        function task_AntennaValueChanged(app, event)

            if strcmp(app.task_Antenna.Value, 'Unlisted (Directional)')
                app.task_Azimuth.Enable   = 1;
                app.task_Elevation.Enable = 1;
            else
                app.task_Azimuth.Enable   = 0;
                app.task_Elevation.Enable = 0;
            end

        end

        % Image clicked function: task_imgRefresh
        function task_RefreshButtonPushed(app, event)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            ind2 = app.task_Tree.SelectedNodes.NodeData;

            app.taskList{ind1}.Band(ind2).EditedFlag = 0;
            task_ParametersEditables_ShowValues(app, ind1, ind2)

        end

        % Value changed function: task_StepWidth1
        function task_StepWidth1ValueChanged(app, event)

            span = (app.task_FreqStop.Value - app.task_FreqStart.Value)*1e+6;

            DataPoints = round(span/(1000*app.task_StepWidth1.Value) + 1);
            if DataPoints < app.task_DataPoints1.Limits(1)
                app.task_DataPoints1.Value = app.task_DataPoints1.Limits(1);
            elseif DataPoints > app.task_DataPoints1.Limits(2)
                app.task_DataPoints1.Value = fix(app.task_DataPoints1.Limits(2));
            else
                app.task_DataPoints1.Value = DataPoints;
            end

            app.task_StepWidth1.Value = (span/(app.task_DataPoints1.Value-1)) / 1000;

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrStepWidth  = 1000*app.task_StepWidth1.Value;
            app.taskList{ind1}.Band(ind2).instrDataPoints = app.task_DataPoints1.Value;

        end

        % Value changed function: task_StepWidth2
        function task_StepWidth2ValueChanged(app, event)

            app.task_imgRefresh.Visible = 1;

            span      = (app.task_FreqStop.Value-app.task_FreqStart.Value)*1e+6;
            stepValue = extractBefore(app.task_StepWidth2.Value, ' kHz');

            % DataPoints
            app.task_DataPoints2.Value = span/(1000*str2double(stepValue)) + 1;

            % Resolution, Selectivity
            rbwValues = [];
            rbwItems  = {};
            for ii = 1:3
                rbwValues = [rbwValues, app.EB500Map{app.task_StepWidth2.Value,ii}];
                rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
            end
            app.task_Resolution2.Items = rbwItems;

            rbwIndex = find(abs(rbwValues - app.task_Resolution.Value*1000) == min(abs(rbwValues - app.task_Resolution.Value*1000)));
            app.task_Resolution2.Value = app.task_Resolution2.Items{rbwIndex};
            app.task_Selectivity.Value = app.task_Selectivity.Items{rbwIndex};

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrStepWidth   = app.task_StepWidth2.Value;
            app.taskList{ind1}.Band(ind2).instrDataPoints  = app.task_DataPoints2.Value;
            app.taskList{ind1}.Band(ind2).instrSelectivity = app.task_Selectivity.Value;

            app.taskList{ind1}.Band(ind2).instrResolution_Items = app.task_Resolution2.Items;
            app.taskList{ind1}.Band(ind2).instrResolution       = app.task_Resolution2.Value;

        end

        % Value changed function: task_DataPoints1
        function task_DataPointsValueChanged(app, event)

            if ~fix(diff(app.task_DataPoints1.Limits))
                return
            end

            span = (app.task_FreqStop.Value - app.task_FreqStart.Value)*1e+6;
            app.task_StepWidth1.Value = (span/(app.task_DataPoints1.Value-1)) / 1000;

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrDataPoints = app.task_DataPoints1.Value;
            app.taskList{ind1}.Band(ind2).instrStepWidth  = app.task_StepWidth1.Value*1000;

        end

        % Value changed function: task_Selectivity
        function task_SelectivityValueChanged(app, event)

            app.task_imgRefresh.Visible    = 1;
            app.task_Resolution2.Value = sprintf('%.3f kHz', app.EB500Map{app.task_StepWidth2.Value, app.task_Selectivity.Value}/1000);

            % Registra edição dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrSelectivity = app.task_Selectivity.Value;
            app.taskList{ind1}.Band(ind2).instrResolution  = app.task_Resolution2.Value;

        end

        % Value changed function: task_Resolution2
        function task_ResolutionValueChanged(app, event)

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrResolution  = app.task_Resolution2.Value;

            if contains(app.task_scpiList.Value, 'EB500')
                if app.task_Selectivity.Visible
                    ind3 = find(strcmp(app.task_Resolution2.Items, app.task_Resolution2.Value));
                    app.task_Selectivity.Value = app.task_Selectivity.Items{ind3};
                end
                app.taskList{ind1}.Band(ind2).instrSelectivity = app.task_Selectivity.Value;
            end

        end

        % Value changed function: task_attMode
        function task_attModeValueChanged(app, event)

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrAttMode = app.task_attMode.Value;

            % Visibilidade do valor do atenuador.
            if strcmp(app.task_attMode.Value, 'Auto')
                app.task_attFactorLabel.Visible = 0;
                app.task_attFactor.Visible      = 0;
            else
                app.task_attFactorLabel.Visible = 1;
                app.task_attFactor.Visible      = 1;
            end

        end

        % Value changed function: task_attFactor
        function task_attFactorValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrAttFactor = app.task_attFactor.Value;

        end

        % Value changed function: task_Preamp
        function task_PreampValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrPreamp = app.task_Preamp.Value;

        end

        % Value changed function: task_Detector2
        function task_DetectorValueChanged(app, event)

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrDetector = app.task_Detector2.Value;

        end

        % Value changed function: task_IntegrationTime
        function task_IntegrationTimeValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrIntegrationTime = app.task_IntegrationTime.Value;
            
        end

        % Callback function
        function task_InfoHyperlinkClicked(app, event)
            
            if ~isempty(app.task_LogFile.Tag)
                system(sprintf('notepad "%s" &', app.task_LogFile.Tag));
            end

        end

        % Callback function
        function task_Button1Pushed(app, event)
            
            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Waterfall plot task" (1)
                    app.taskFlag = 1;
                    app.recFlag  = 0;
                    
                    app.axesFlagType = 'SelectedNode';
                    app.axesFlag     = 1;
                    
                    task_Layout(app)    
                    WaterfallPlotTask(app)

                case 1                                                                  % "Waterfall plot task" (1) >> Off (0)
                    if app.recFlag
                        if app.specMatrix.RecordSamples && (app.specMatrix.RecordSamples < app.specMatrix.HistoryDepth)
                            WaterfallPlotTask_REC(app)
                        end
                    end
                    app.taskFlag = 0;
                    app.axesFlag = 0;
                    app.recFlag  = 0;
                    
                    task_Layout(app)
            end

        end

        % Callback function
        function task_Button2Pushed(app, event)
            
            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Regular task" (2)
                    app.taskFlag = 2;
                    app.axesFlag = 0;
                    app.recFlag  = 1;
                    
                    switch app.task_DurationUnit.Value
                        case 'min'; Duration = duration(0, app.task_Duration.Value, 0);
                        case 'hr';  Duration = duration(app.task_Duration.Value, 0, 0);
                    end
                    
                    task_Layout(app)
                    RegularTask(app, seconds(Duration))

                case 2                                                              % "Regular task" (2) >> Off (0)
                    app.taskFlag = 0;
                    app.axesFlag = 0;
                    app.recFlag  = 0;
                    
                    task_Layout(app)
            end

        end

        % Image clicked function: Tab1_Image, Tab2_Image, Tab3_Image, 
        % Tab4_Image
        function PanelTab_SelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.mainPanel_Grid.RowHeight(2:2:8) = {'1x',0,0,0};
                case app.Tab2_Image; app.mainPanel_Grid.RowHeight(2:2:8) = {0,'1x',0,0};
                case app.Tab3_Image; app.mainPanel_Grid.RowHeight(2:2:8) = {0,0,'1x',0};
                case app.Tab4_Image; app.mainPanel_Grid.RowHeight(2:2:8) = {0,0,0,'1x'};
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.mainPanel = uipanel(app.GridLayout2);
            app.mainPanel_Grid = uigridlayout(app.mainPanel);
            app.Tab1_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'ASPECTOS GERAIS';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Panel = uigridlayout(app.mainPanel_Grid);
            app.task_Name = uidropdown(app.Tab1_Panel);
            app.task_Name.Items = {};
            app.task_Name.ValueChangedFcn = createCallbackFcn(app, @task_TaskSelectionChanged, true);
            app.task_Name.Tag = 'task_Editable';
            app.task_Name.Value = {};
            app.task_NameLabel = uilabel(app.Tab1_Panel);
            app.task_NameLabel.Text = 'Nome:';
            app.task_GridA2 = uigridlayout(app.Tab1_Panel);
            app.task_addMaskFile = uibutton(app.task_GridA2, 'push');
            app.task_addMaskFile.ButtonPushedFcn = createCallbackFcn(app, @task_addMaskFileButtonPushed, true);
            app.task_addMaskFile.Tag = 'task_Editable';
            app.task_addMaskFile.Icon = 'LT_OpenFile.png';
            app.task_addMaskFile.Tooltip = {'Máscara espectral'};
            app.task_addMaskFile.Text = '';
            app.task_maskFile = uibutton(app.task_GridA2, 'push');
            app.task_maskFile.ButtonPushedFcn = createCallbackFcn(app, @task_maskFileButtonPushed, true);
            app.task_maskFile.Icon = 'LT_mask.png';
            app.task_maskFile.Enable = 'off';
            app.task_maskFile.Tooltip = {''};
            app.task_maskFile.Text = '';
            app.task_Type = uidropdown(app.task_GridA2);
            app.task_Type.Items = {'Monitoração espectral', 'Monitoração espectral - Drive-test', 'Monitoração espectral - Rompimento de máscara espectral'};
            app.task_Type.ValueChangedFcn = createCallbackFcn(app, @task_TypeValueChanged, true);
            app.task_Type.Tag = 'task_Editable';
            app.task_Type.Value = 'Monitoração espectral';
            app.task_TypeLabel = uilabel(app.task_GridA2);
            app.task_TypeLabel.Text = 'Tipo:';
            app.PerododeobservaoLabel = uilabel(app.Tab1_Panel);
            app.PerododeobservaoLabel.Text = 'Período de observação:';
            app.Panel = uipanel(app.Tab1_Panel);
            app.task_GridA3 = uigridlayout(app.Panel);
            app.task_Duration = uieditfield(app.task_GridA3, 'numeric');
            app.task_Duration.Limits = [0 Inf];
            app.task_Duration.ValueDisplayFormat = '%.3f';
            app.task_Duration.Tag = 'task_Editable';
            app.task_Duration.Value = 10;
            app.task_DurationUnit = uidropdown(app.task_GridA3);
            app.task_DurationUnit.Items = {'min', 'hr'};
            app.task_DurationUnit.Tag = 'task_Editable';
            app.task_DurationUnit.Value = 'min';
            app.Report_Grid4 = uigridlayout(app.task_GridA3);
            app.Report_DatePicker1 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.Report_DatePicker1.Enable = 'off';
            app.Report_Spinner1 = uispinner(app.Report_Grid4);
            app.Report_Spinner1.Limits = [0 23];
            app.Report_Spinner1.RoundFractionalValues = 'on';
            app.Report_Spinner1.ValueDisplayFormat = '%.0f';
            app.Report_Spinner1.Enable = 'off';
            app.Report_Spinner2 = uispinner(app.Report_Grid4);
            app.Report_Spinner2.Step = 15;
            app.Report_Spinner2.Limits = [0 45];
            app.Report_Spinner2.RoundFractionalValues = 'on';
            app.Report_Spinner2.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2.Enable = 'off';
            app.Report_DatePicker2 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.Report_DatePicker2.Enable = 'off';
            app.Report_Spinner3 = uispinner(app.Report_Grid4);
            app.Report_Spinner3.Limits = [0 23];
            app.Report_Spinner3.RoundFractionalValues = 'on';
            app.Report_Spinner3.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3.Enable = 'off';
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4 = uispinner(app.Report_Grid4);
            app.Report_Spinner4.Step = 10;
            app.Report_Spinner4.Limits = [0 59];
            app.Report_Spinner4.RoundFractionalValues = 'on';
            app.Report_Spinner4.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4.Enable = 'off';
            app.Report_Spinner4.Value = 59;
            app.Label = uilabel(app.Report_Grid4);
            app.Label.Enable = 'off';
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.Report_Grid4);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = ':';
            app.task_Type_2 = uidropdown(app.task_GridA3);
            app.task_Type_2.Items = {'Duração', 'Período específico', 'Quantidade específica de amostras'};
            app.task_Type_2.Tag = 'task_Editable';
            app.task_Type_2.Value = 'Duração';
            app.task_TypeLabel_2 = uilabel(app.task_GridA3);
            app.task_TypeLabel_2.Text = 'Tipo:';
            app.task_Type_3 = uidropdown(app.Tab1_Panel);
            app.task_Type_3.Items = {'8 bits', '16 bits', '32 bits'};
            app.task_Type_3.Tag = 'task_Editable';
            app.task_Type_3.Value = '8 bits';
            app.task_TypeLabel_3 = uilabel(app.Tab1_Panel);
            app.task_TypeLabel_3.Text = 'Codificação:';
            app.Tab2_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'RECEPTOR';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab2_Image.ImageSource = 'LT_playback.png';
            app.Tab3_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab3_Grid.Tag = 'COLORLOCKED';
            app.Tab3_Title = uilabel(app.Tab3_Grid);
            app.Tab3_Title.Text = 'GPS';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab3_Image.ImageSource = 'Instr_Global.png';
            app.Tab4_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab4_Grid.Tag = 'COLORLOCKED';
            app.Tab4_Title = uilabel(app.Tab4_Grid);
            app.Tab4_Title.Text = 'ANTENA';
            app.Tab4_Image = uiimage(app.Tab4_Grid);
            app.Tab4_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab4_Image.ImageSource = 'LT_antenna.png';
            app.GridLayout3 = uigridlayout(app.mainPanel_Grid);
            app.task_scpiListGrid = uigridlayout(app.GridLayout3);
            app.task_scpiList = uidropdown(app.task_scpiListGrid);
            app.task_scpiList.Items = {};
            app.task_scpiList.ValueChangedFcn = createCallbackFcn(app, @task_scpiListValueChanged, true);
            app.task_scpiList.Tag = 'task_Editable';
            app.task_scpiList.Value = {};
            app.task_scpiListLabel = uilabel(app.task_scpiListGrid);
            app.task_scpiListLabel.Text = 'Receptor:';
            app.task_scpiConnectivity = uibutton(app.task_scpiListGrid, 'push');
            app.task_scpiConnectivity.ButtonPushedFcn = createCallbackFcn(app, @task_scpiConnectivityButtonPushed, true);
            app.task_scpiConnectivity.Tag = 'task_Editable';
            app.task_scpiConnectivity.Icon = 'LT_connectivity.png';
            app.task_scpiConnectivity.Tooltip = {'Teste de conectividade'};
            app.task_scpiConnectivity.Text = '';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.Task_ResetCommandLabel = uilabel(app.GridLayout6);
            app.Task_ResetCommandLabel.Text = 'Reset:';
            app.Task_ResetCommand = uidropdown(app.GridLayout6);
            app.Task_ResetCommand.Items = {'On', 'Off'};
            app.Task_ResetCommand.Tag = 'task_Editable';
            app.Task_ResetCommand.Value = 'On';
            app.Task_SyncRefLabel = uilabel(app.GridLayout6);
            app.Task_SyncRefLabel.Text = 'Sincronismo:';
            app.Task_SyncRef = uidropdown(app.GridLayout6);
            app.Task_SyncRef.Items = {'Single Sweep', 'Continuous Sweep', 'Streaming'};
            app.Task_SyncRef.Tag = 'task_Editable';
            app.Task_SyncRef.Value = 'Single Sweep';
            app.GridLayout4 = uigridlayout(app.mainPanel_Grid);
            app.task_gpsPanel = uipanel(app.GridLayout4);
            app.task_gpsGrid = uigridlayout(app.task_gpsPanel);
            app.task_gpsRevisitTimeLabel = uilabel(app.task_gpsGrid);
            app.task_gpsRevisitTimeLabel.Text = {'Tempo revisita:'; '(segundos)'};
            app.task_gpsRevisitTime = uieditfield(app.task_gpsGrid, 'numeric');
            app.task_gpsRevisitTime.Limits = [1 Inf];
            app.task_gpsRevisitTime.RoundFractionalValues = 'on';
            app.task_gpsRevisitTime.ValueDisplayFormat = '%.0f';
            app.task_gpsRevisitTime.Tag = 'task_Editable';
            app.task_gpsRevisitTime.Value = 10;
            app.task_manualLatitudeLabel = uilabel(app.task_gpsGrid);
            app.task_manualLatitudeLabel.Text = {'Latitude:'; '(graus decimais)'};
            app.task_manualLatitude = uieditfield(app.task_gpsGrid, 'numeric');
            app.task_manualLatitude.ValueDisplayFormat = '%.6f';
            app.task_manualLatitude.Tag = 'task_Editable';
            app.task_manualLatitude.Value = -1;
            app.task_manualLongitudeLabel = uilabel(app.task_gpsGrid);
            app.task_manualLongitudeLabel.Text = {'Longitude:'; '(graus decimais)'};
            app.task_manualLongitude = uieditfield(app.task_gpsGrid, 'numeric');
            app.task_manualLongitude.ValueDisplayFormat = '%.6f';
            app.task_manualLongitude.Tag = 'task_Editable';
            app.task_manualLongitude.Value = -1;
            app.task_gpsListGrid = uigridlayout(app.GridLayout4);
            app.task_gpsListLabel = uilabel(app.task_gpsListGrid);
            app.task_gpsListLabel.Text = 'GPS:';
            app.task_gpsList = uidropdown(app.task_gpsListGrid);
            app.task_gpsList.Items = {'ID 0: Manual'};
            app.task_gpsList.ValueChangedFcn = createCallbackFcn(app, @task_gpsListValueChanged, true);
            app.task_gpsList.Tag = 'task_Editable';
            app.task_gpsList.Value = 'ID 0: Manual';
            app.task_gpsConnectivity = uibutton(app.task_gpsListGrid, 'push');
            app.task_gpsConnectivity.ButtonPushedFcn = createCallbackFcn(app, @task_gpsConnectivityButtonPushed, true);
            app.task_gpsConnectivity.Tag = 'task_Editable';
            app.task_gpsConnectivity.Icon = 'LT_connectivity.png';
            app.task_gpsConnectivity.Tooltip = {'Teste de conectividade'};
            app.task_gpsConnectivity.Text = '';
            app.GridLayout5 = uigridlayout(app.mainPanel_Grid);
            app.task_gpsListGrid_2 = uigridlayout(app.GridLayout5);
            app.task_gpsConnectivity_2 = uibutton(app.task_gpsListGrid_2, 'push');
            app.task_gpsConnectivity_2.Tag = 'task_Editable';
            app.task_gpsConnectivity_2.Icon = 'LT_connectivity.png';
            app.task_gpsConnectivity_2.Tooltip = {'Teste de conectividade'};
            app.task_gpsConnectivity_2.Text = '';
            app.task_AntennaLabel_2 = uilabel(app.task_gpsListGrid_2);
            app.task_AntennaLabel_2.Text = 'Comutador:';
            app.task_Antenna_2 = uidropdown(app.task_gpsListGrid_2);
            app.task_Antenna_2.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (600 MHz - 6 GHz)', 'Telescopic', 'Unlisted (Omni)', 'Unlisted (Directional)'};
            app.task_Antenna_2.Tag = 'task_Editable';
            app.task_Antenna_2.Value = '';
            app.task_antennaPanel = uipanel(app.GridLayout5);
            app.task_antennaGrid = uigridlayout(app.task_antennaPanel);
            app.task_Height = uieditfield(app.task_antennaGrid, 'numeric');
            app.task_Height.Limits = [0 127];
            app.task_Height.RoundFractionalValues = 'on';
            app.task_Height.ValueDisplayFormat = '%.0f';
            app.task_Height.Tag = 'task_Editable';
            app.task_Height.Value = 2;
            app.task_HeightLabel = uilabel(app.task_antennaGrid);
            app.task_HeightLabel.Text = {'Altura de instalação:'; '(metros)'};
            app.task_Azimuth = uieditfield(app.task_antennaGrid, 'numeric');
            app.task_Azimuth.Limits = [0 359];
            app.task_Azimuth.RoundFractionalValues = 'on';
            app.task_Azimuth.ValueDisplayFormat = '%.0f';
            app.task_Azimuth.Tag = 'task_Editable';
            app.task_Azimuth.Enable = 'off';
            app.task_AzimuthLabel = uilabel(app.task_antennaGrid);
            app.task_AzimuthLabel.Text = {'Azimute:'; '(graus decimais)'};
            app.task_Elevation = uieditfield(app.task_antennaGrid, 'numeric');
            app.task_Elevation.Limits = [0 90];
            app.task_Elevation.RoundFractionalValues = 'on';
            app.task_Elevation.ValueDisplayFormat = '%.0f';
            app.task_Elevation.Tag = 'task_Editable';
            app.task_Elevation.Enable = 'off';
            app.task_ElevationLabel = uilabel(app.task_antennaGrid);
            app.task_ElevationLabel.Text = {'Elevação:'; '(graus decimais)'};
            app.task_AntennaLabel = uilabel(app.GridLayout5);
            app.task_AntennaLabel.Text = 'Antena:';
            app.task_Antenna = uidropdown(app.GridLayout5);
            app.task_Antenna.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (600 MHz - 6 GHz)', 'Telescopic', 'Unlisted (Omni)', 'Unlisted (Directional)'};
            app.task_Antenna.ValueChangedFcn = createCallbackFcn(app, @task_AntennaValueChanged, true);
            app.task_Antenna.Tag = 'task_Editable';
            app.task_Antenna.Value = '';
            app.task_Grid = uigridlayout(app.GridLayout2);
            app.task_ButtonGrid = uigridlayout(app.task_Grid);
            app.OKButton = uibutton(app.task_ButtonGrid, 'push');
            app.OKButton.Text = 'OK';
            app.task_Tree = uitree(app.task_Grid);
            app.task_Tree.SelectionChangedFcn = createCallbackFcn(app, @task_TreeSelectionChanged, true);
            app.mainInfo_TextLabel = uilabel(app.task_Grid);
            app.mainInfo_TextLabel.Text = 'METADADOS:';
            app.mainInfo_Text = uitextarea(app.task_Grid);
            app.mainInfo_Text.Editable = 'off';
            app.task_TreeLabel = uilabel(app.task_Grid);
            app.task_TreeLabel.Text = 'FAIXAS A MONITORAR:';
            app.task_Panel_22 = uipanel(app.task_Grid);
            app.task_Grid22 = uigridlayout(app.task_Panel_22);
            app.task_StepWidth1 = uieditfield(app.task_Grid22, 'numeric');
            app.task_StepWidth1.Limits = [0 Inf];
            app.task_StepWidth1.ValueDisplayFormat = '%.3f';
            app.task_StepWidth1.ValueChangedFcn = createCallbackFcn(app, @task_StepWidth1ValueChanged, true);
            app.task_StepWidth1.Tag = 'task_Set1';
            app.task_StepWidth1.Editable = 'off';
            app.task_StepWidth1.Enable = 'off';
            app.task_StepWidth2 = uidropdown(app.task_Grid22);
            app.task_StepWidth2.Items = {};
            app.task_StepWidth2.ValueChangedFcn = createCallbackFcn(app, @task_StepWidth2ValueChanged, true);
            app.task_StepWidth2.Tag = 'task_Set2';
            app.task_StepWidth2.Enable = 'off';
            app.task_StepWidth2.Value = {};
            app.task_StepWidth2Label = uilabel(app.task_Grid22);
            app.task_StepWidth2Label.Enable = 'off';
            app.task_StepWidth2Label.Text = 'Passo varredura (kHz):';
            app.task_DataPoints1 = uispinner(app.task_Grid22);
            app.task_DataPoints1.Step = 100;
            app.task_DataPoints1.Limits = [101 32001];
            app.task_DataPoints1.RoundFractionalValues = 'on';
            app.task_DataPoints1.ValueDisplayFormat = '%.0f';
            app.task_DataPoints1.ValueChangedFcn = createCallbackFcn(app, @task_DataPointsValueChanged, true);
            app.task_DataPoints1.Tag = 'task_Set1';
            app.task_DataPoints1.Enable = 'off';
            app.task_DataPoints1.Value = 101;
            app.task_DataPoints2 = uieditfield(app.task_Grid22, 'numeric');
            app.task_DataPoints2.ValueDisplayFormat = '%.0f';
            app.task_DataPoints2.Tag = 'task_Set2';
            app.task_DataPoints2.Editable = 'off';
            app.task_DataPoints2.Enable = 'off';
            app.task_DataPointsLabel = uilabel(app.task_Grid22);
            app.task_DataPointsLabel.Enable = 'off';
            app.task_DataPointsLabel.Text = 'Pontos por traço:';
            app.task_Resolution2 = uidropdown(app.task_Grid22);
            app.task_Resolution2.Items = {};
            app.task_Resolution2.ValueChangedFcn = createCallbackFcn(app, @task_ResolutionValueChanged, true);
            app.task_Resolution2.Enable = 'off';
            app.task_Resolution2.Value = {};
            app.task_Resolution2Label = uilabel(app.task_Grid22);
            app.task_Resolution2Label.Enable = 'off';
            app.task_Resolution2Label.Text = 'Resolução (kHz):';
            app.task_attMode = uidropdown(app.task_Grid22);
            app.task_attMode.Items = {'Manual', 'Auto'};
            app.task_attMode.ValueChangedFcn = createCallbackFcn(app, @task_attModeValueChanged, true);
            app.task_attMode.Enable = 'off';
            app.task_attMode.Value = 'Manual';
            app.task_attModeLabel = uilabel(app.task_Grid22);
            app.task_attModeLabel.Enable = 'off';
            app.task_attModeLabel.Text = 'Modo do atenuador:';
            app.task_attFactor = uidropdown(app.task_Grid22);
            app.task_attFactor.Items = {};
            app.task_attFactor.ValueChangedFcn = createCallbackFcn(app, @task_attFactorValueChanged, true);
            app.task_attFactor.Enable = 'off';
            app.task_attFactor.Value = {};
            app.task_attFactorLabel = uilabel(app.task_Grid22);
            app.task_attFactorLabel.Enable = 'off';
            app.task_attFactorLabel.Text = 'Atenuador:';
            app.task_Preamp = uidropdown(app.task_Grid22);
            app.task_Preamp.Items = {'On', 'Off'};
            app.task_Preamp.ValueChangedFcn = createCallbackFcn(app, @task_PreampValueChanged, true);
            app.task_Preamp.Enable = 'off';
            app.task_Preamp.Value = 'Off';
            app.task_PreampLabel = uilabel(app.task_Grid22);
            app.task_PreampLabel.Enable = 'off';
            app.task_PreampLabel.Text = 'Pré-amplificador:';
            app.task_Detector2 = uidropdown(app.task_Grid22);
            app.task_Detector2.Items = {'Auto Peak', 'Average', 'Negative Peak', 'Positive Peak', 'Quasi Peak', 'RMS', 'Sample'};
            app.task_Detector2.ValueChangedFcn = createCallbackFcn(app, @task_DetectorValueChanged, true);
            app.task_Detector2.Enable = 'off';
            app.task_Detector2.Value = 'Positive Peak';
            app.task_Detector2Label = uilabel(app.task_Grid22);
            app.task_Detector2Label.Enable = 'off';
            app.task_Detector2Label.Text = 'Detector:';
            app.task_LevelUnit2 = uieditfield(app.task_Grid22, 'text');
            app.task_LevelUnit2.Editable = 'off';
            app.task_LevelUnit2.Enable = 'off';
            app.task_LevelUnit2.Value = 'dBm';
            app.task_LevelUnit2Label = uilabel(app.task_Grid22);
            app.task_LevelUnit2Label.Enable = 'off';
            app.task_LevelUnit2Label.Text = 'Unidade:';
            app.task_IntegrationTime = uieditfield(app.task_Grid22, 'numeric');
            app.task_IntegrationTime.Limits = [0 10000];
            app.task_IntegrationTime.RoundFractionalValues = 'on';
            app.task_IntegrationTime.ValueDisplayFormat = '%.0f';
            app.task_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @task_IntegrationTimeValueChanged, true);
            app.task_IntegrationTime.Enable = 'off';
            app.task_IntegrationTime.Visible = 'off';
            app.task_IntegrationTime.Value = 100;
            app.task_IntegrationTimeLabel = uilabel(app.task_Grid22);
            app.task_IntegrationTimeLabel.Enable = 'off';
            app.task_IntegrationTimeLabel.Visible = 'off';
            app.task_IntegrationTimeLabel.Text = 'Tempo integração (ms):';
            app.task_Selectivity = uidropdown(app.task_Grid22);
            app.task_Selectivity.Items = {'Normal', 'Narrow', 'Sharp'};
            app.task_Selectivity.ValueChangedFcn = createCallbackFcn(app, @task_SelectivityValueChanged, true);
            app.task_Selectivity.Tag = 'task_Set2';
            app.task_Selectivity.Enable = 'off';
            app.task_Selectivity.Value = 'Normal';
            app.task_SelectivityLabel = uilabel(app.task_Grid22);
            app.task_SelectivityLabel.Enable = 'off';
            app.task_SelectivityLabel.Text = 'Seletividade FFT:';
            app.task_Detector2_2 = uidropdown(app.task_Grid22);
            app.task_Detector2_2.Items = {'', 'ADD107', 'ADD207'};
            app.task_Detector2_2.Enable = 'off';
            app.task_Detector2_2.Value = '';
            app.task_Detector2Label_2 = uilabel(app.task_Grid22);
            app.task_Detector2Label_2.Enable = 'off';
            app.task_Detector2Label_2.Text = 'Antena:';
            app.task_GridB2 = uigridlayout(app.task_Grid);
            app.task_imgRefresh = uiimage(app.task_GridB2);
            app.task_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @task_RefreshButtonPushed, true);
            app.task_imgRefresh.Visible = 'off';
            app.task_imgRefresh.ImageSource = 'LT_refresh.png';
            app.task_PanelLabel = uilabel(app.task_GridB2);
            app.task_PanelLabel.Text = 'PARÂMETROS EDITÁVEIS:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTask

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end