classdef winTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        appColetaUIFigure  matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        GridLayout2        matlab.ui.container.GridLayout
        PosioalvoLabel     matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        PosioinicialLabel  matlab.ui.control.Label
        MCL1Label          matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        Gauge_4            matlab.ui.control.Gauge
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        AzimuteLabel       matlab.ui.control.Label
        PolarizaoLabel     matlab.ui.control.Label
        ElevaoLabel        matlab.ui.control.Label
        Gauge_3            matlab.ui.control.Gauge
        Gauge_2            matlab.ui.control.Gauge
    end

    
    properties (Access = private)
        
        CallingApp
        EMSatObj
        specObj

    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idx)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            app.CallingApp = mainapp;

            app.EMSatObj   = app.CallingApp.EMSatObj;
            app.specObj    = app.CallingApp.specObj(idx);

        end

        % Close request function: appColetaUIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.appColetaUIFigure = uifigure('Visible', 'off');
            app.appColetaUIFigure.Name = 'appColeta';
            app.appColetaUIFigure.Icon = 'LR_icon.png';
            app.appColetaUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.appColetaUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Gauge_2 = uigauge(app.Panel, 'circular');
            app.Gauge_2.Limits = [-180 180];
            app.Gauge_3 = uigauge(app.Panel, 'circular');
            app.Gauge_3.Limits = [-180 180];
            app.ElevaoLabel = uilabel(app.Panel);
            app.ElevaoLabel.Text = 'Elevação:';
            app.PolarizaoLabel = uilabel(app.Panel);
            app.PolarizaoLabel.Text = 'Polarização:';
            app.AzimuteLabel = uilabel(app.Panel);
            app.AzimuteLabel.Text = 'Azimute:';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '120.123º';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '120.123º';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '120.123º';
            app.Gauge_4 = uigauge(app.Panel, 'circular');
            app.Gauge_4.Limits = [0 360];
            app.Gauge_4.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Gauge_4.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.MCL1Label = uilabel(app.GridLayout);
            app.MCL1Label.Text = 'MCL-1';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PosioinicialLabel = uilabel(app.GridLayout2);
            app.PosioinicialLabel.Text = 'Posição inicial:';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '(120.123º, 120.123º, 120.123º)';
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '(120.123º, 120.123º, 120.123º)';
            app.PosioalvoLabel = uilabel(app.GridLayout2);
            app.PosioalvoLabel.Text = 'Posição alvo:';
            % Show the figure after all components are created
            app.appColetaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.appColetaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.appColetaUIFigure)
        end
    end
end